classdef TLE_DATA_V_1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TLEMaster                      matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DATAManipulationTab            matlab.ui.container.Tab
        EstimatedDatasizeEditFieldLabel  matlab.ui.control.Label
        EstimatedDatasizeEditField     matlab.ui.control.EditField
        CreateDataPacketsButton        matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        PassoverTimesEditFieldLabel    matlab.ui.control.Label
        PassoverTimesEditField         matlab.ui.control.NumericEditField
        SDRDataRateMHzEditFieldLabel   matlab.ui.control.Label
        SDRDataRateMHzEditField        matlab.ui.control.NumericEditField
        GPSClockMHzEditFieldLabel      matlab.ui.control.Label
        GPSClockMHzEditField           matlab.ui.control.NumericEditField
        CULogo                         matlab.ui.control.Image
        RaytheonLogo                   matlab.ui.control.Image
        ListBox                        matlab.ui.control.ListBox
        SearchSatelliteEditFieldLabel  matlab.ui.control.Label
        SearchSatelliteEditField       matlab.ui.control.EditField
        ConfirmedSatelliteEditFieldLabel  matlab.ui.control.Label
        ConfirmedSatelliteEditField    matlab.ui.control.EditField
        ConfirmSelectionButton         matlab.ui.control.Button
        V11Label                       matlab.ui.control.Label
        TLETab                         matlab.ui.container.Tab
        UpdateButton                   matlab.ui.control.Button
        TLEfilename                    matlab.ui.control.EditField
        DatafromEditFieldLabel         matlab.ui.control.Label
        DatafromEditField              matlab.ui.control.EditField
        NetworkConnectionLabel         matlab.ui.control.Label
        NetworkConnectionLamp          matlab.ui.control.Lamp
        GPSCoordLabel                  matlab.ui.control.Label
        GPSCoordEditField              matlab.ui.control.EditField
        TableTab                       matlab.ui.container.TabGroup
        OrbitalElementsTab             matlab.ui.container.Tab
        ElementInfoTable               matlab.ui.control.Table
        TLE_tab                        matlab.ui.container.Tab
        TLEInfoTable                   matlab.ui.control.Table
        SearchTab                      matlab.ui.container.Tab
        SearchElement1Label            matlab.ui.control.Label
        SElement1                      matlab.ui.control.EditField
        SearchElement2EditFieldLabel   matlab.ui.control.Label
        SElement2                      matlab.ui.control.EditField
        RefinedSearchTable             matlab.ui.control.Table
        SearchResultNumTxt             matlab.ui.control.Label
        TotalResultsTxt                matlab.ui.control.Label
        GroundTrackTab_2               matlab.ui.container.Tab
        PlotGroundTrackButton          matlab.ui.control.Button
        groundtrackPlot                matlab.ui.control.UIAxes
        OrbitViewerTab                 matlab.ui.container.Tab
        PlotEarthAxesButton            matlab.ui.control.Button
        PlotCALSPHERE1Button           matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        DViewerPlot                    matlab.ui.control.UIAxes
        MonteCarloTab                  matlab.ui.container.Tab
        IntegrateErrorButton           matlab.ui.control.Button
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        EditField                      matlab.ui.control.EditField
        LoadDataFileButton             matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        Node3                          matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
        GibbsMethodTab                 matlab.ui.container.Tab
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Menu3                          matlab.ui.container.Menu
    end

    
    methods (Access = private)
        
        function info=TLEextract(~,tlines,k)
            % k could be 1-8
            dataL=char(tlines);
            if ~isempty(dataL)
                LOCInd=[find(dataL==' ')' ;length(dataL)];
                LOCInd=[LOCInd(diff(LOCInd)>1) ;length(dataL)];
                info=dataL(LOCInd(k):LOCInd(k+1));
                info=string(info(info~=' '));
            else
                info="";
            end
        end
        
        function TLEUpdate(app)
            txtName=string(app.TLEfilename.Value);
            WebAddress=app.DatafromEditField.Value;
            
            data=webread(WebAddress,'ContentType','text');
            
            dlmwrite(txtName,data)
            
            fid=fopen(txtName);
            tline = fgetl(fid);
            tlines=cell(ceil(length(data)/27),1);
            tlines(:,:)={''};
            g=0;
            while ischar(tline)
                g=g+1;
                tlines(g,1) = {tline};
                tline = fgetl(fid);
            end
            fclose(fid);
            
            tlines=string(tlines);
            tlines=tlines(tlines~=",");
            for i=1:length(tlines)
                tchar=char(tlines(i));
                tlines(i)=string(tchar(tchar~=','));
            end
            NameIND=(1:3:length(tlines))';
            NameIND=NameIND(1:(end-1));
            SatNAME=tlines(NameIND);
            SatNAME=SatNAME(SatNAME~="");
            
            V1=strings(length(NameIND),8);
            V2=strings(length(NameIND),7);
            for j=1:(length(NameIND)-2)
                for h=1:8
                    V1(j,h)= TLEextract(app,tlines(NameIND(j)+1),h);
                end
                for h=1:7
                    V2(j,h)= TLEextract(app,tlines(NameIND(j)+2),h);
                end
            end
            [a,~]=find(V2~="");
            V1=V1(unique(a),:);
            V2=V2(unique(a),:);
            % Concatenates line 2 column 8
            eightNineTen=char(V2(:,7));
            V2(:,7)=string(eightNineTen(:,1:11));
            V2(:,4)=strcat("0.",V2(:,4));
            TLEInfoT=table(SatNAME,V1(:,1),V1(:,2),V1(:,3),V1(:,4),V1(:,5),V1(:,6),V1(:,7),V1(:,8),V2(:,1),V2(:,2),V2(:,3),V2(:,4),V2(:,5),V2(:,6),V2(:,7));
            writetable(TLEInfoT);
            app.TLEInfoTable.Data=TLEInfoT;


           mu=398618.0; % [km^3/s^2]
            MM=str2num(char(V2(:,7)));
            
            T=(1./MM).*3600.*24; %seconds per revolution           
            a=(((T.^2)*mu)/(4*(pi^2))).^(1/3);
            
            
            OrbitalETable=table(SatNAME,V2(:,1),V1(:,2),a,T,V2(:,2),V2(:,4),V2(:,3),V2(:,5),V2(:,6));
            app.ElementInfoTable.Data=OrbitalETable;
            app.RefinedSearchTable.Data=OrbitalETable;
            app.TotalResultsTxt.Text=[char(num2str(length(V2(:,4)))) ,' Active Satellites'];
            app.SearchResultNumTxt.Text=[char(num2str(length(V2(:,4)))) , ' results of ',char(num2str(length(V2(:,4)))), ' found'];
            
        end
        
        function gpsCoord = gpsExtract(app)
            gpsCoord=char(app.GPSCoordEditField.Value);
            splitIND=(gpsCoord==',');
            LatCoord= str2num(gpsCoord(1:(splitIND(1)-1))); %#ok<*ST2NM>
            LongCoord=str2num(gpsCoord((splitIND(1)+1):end)); %#ok<*ST2NM>
            gpsCoord=[LatCoord, LongCoord];
        end
        
        function PlotEarth(app)
            hold(app.DViewerPlot,'off')
            cla(app.DViewerPlot,'reset')
            hold(app.DViewerPlot,'on')
            r = 6371; %km
            [X,Y,Z] = sphere(app.DViewerPlot);
            
            X2=X*r;
            Y2=Y*r;
            Z2=Z*r;
            surf(app.DViewerPlot,X2,Y2,Z2)
            %             warp(app.DViewerPlot,X2,Y2,Z2)
            %             hold(app.DViewerPlot,'on')
            %             plot3(app.DViewerPlot,[0 5],[0 0],'LineWidth',5) %
            lm=8;
            xlim(app.DViewerPlot,[-lm*r,lm*r])
            ylim(app.DViewerPlot,[-lm*r,lm*r])
            zlim(app.DViewerPlot,[-lm*r,lm*r])


            gpsCoord = gpsExtract(app);
            rectGPS = gpsConvert(app,r,gpsCoord(2),gpsCoord(1));
            scatter3(app.DViewerPlot,rectGPS(1),rectGPS(2),rectGPS(3),50,'filled','r')
            
            phi1=90;
            theta1=0;
            zeroLine=[Spherical2Cart(app,r,theta1,phi1);5*Spherical2Cart(app,r,theta1,phi1)];
            plot3(app.DViewerPlot,zeroLine(:,1),zeroLine(:,2),zeroLine(:,3))
            
            GLine=[gpsConvert(app,r,theta1,0);5*gpsConvert(app,r,theta1,0)];
            plot3(app.DViewerPlot,GLine(:,1),GLine(:,2),GLine(:,3))
            
            
            gpsCoord =[49.2577355,-123.123904];%Vancouver
            rectGPS = gpsConvert(app,r,gpsCoord(2),gpsCoord(1));
            scatter3(app.DViewerPlot,rectGPS(1),rectGPS(2),rectGPS(3),50,'filled','b')
            
            legend(app.DViewerPlot,["Earth","GPS Coord","Zero","Greenwich","Vancouver"])
            title(app.DViewerPlot,'Satellite')
            xlabel(app.DViewerPlot,'km')
            ylabel(app.DViewerPlot,'km')
            grid(app.DViewerPlot, 'on')
            view(app.DViewerPlot,[30,30,40])
        end
        function gpsConvert=gpsConvert(app,r,theta,phi)
            % Conversion from gps to sphereical coordinates
            phiAdj=phi-90;
            %Longitude adjust due to time of day
            %             thetaAdj=
            [timeMAT,~] = timeExtract(app,'Europe/London');
            thetaOFF=(timeMAT(1)*3600+timeMAT(2)*60+timeMAT(3))*360/86164;
            thetaAdj=thetaOFF+theta+180;
            %             23 56 4 time to rotate relative to distant stars(86164 seconds)
            gpsConvert = Spherical2Cart(app,r,thetaAdj,phiAdj);
            
        end
        
        function rectCoord = Spherical2Cart(~,r,theta,phi)
            x=r*sind(phi)*cosd(theta);
            y=r*sind(phi)*sind(theta);
            z=r*cosd(phi);
            rectCoord=[x,y,z];
        end
        
        function [timeMAT,dateMAT] = timeExtract(~,timeZone)
            %For local time put 'local'
            %             t = datetime('now','TimeZone','Europe/London','Format','MM-d-y HH.mm.ss.ms');
            %             t = datetime('now','TimeZone','local','Format','MM-d-y HH.mm.ss.ms');
            t = datetime('now','TimeZone',timeZone,'Format','MM-d-y HH.mm.ss.ms');
            charT=char(t);
            splitInd=find(charT==' ');
            dateChar=charT(1:(splitInd-1));
            timeChar=charT((splitInd+1):end);
            hour=str2double(timeChar(1:2));
            minute=str2double(timeChar(4:5));
            second=str2double(timeChar(7:8));
            milisecond=str2double(timeChar(10:end));
            timeMAT=[hour,minute,second,milisecond];
            month=str2double(dateChar(1:2));
            day=str2double(dateChar(4:5));
            year=str2double(dateChar(7:end));
            dateMAT=[day,month,year];
        end
        
%         function Elements = ElementInfo(app,NORADdes)
%             % Constants
%             M_earth=5.9722E24; % [kg]
%             G=6.67408E-11; %gravitational constant
%             mu=G*M_earth ;% gravitational constant(M*G) forEarth
%             
%             
%             %% Getting data from table
%             rowIND=(string(table2cell(app.TLEInfoTable.Data(:,2)))==NORADdes);
%             satData=table2cell(app.TLEInfoTable.Data(rowIND,:));
%             %             inclination=str2num(string(satData(4))); % inclination(deg)
%             RA=str2num(string(satData(5))); % Right Ascension of the ascending node(deg)
%             eccentricity=str2num(string(satData(6)));
%             AP=str2num(string(satData(7))); % Arguement of Perigee
%             MA=str2num(string(satData(8))); % Mean anomaly
%             MM=str2num(string(satData(9))); % Mean motion (rev/d)
%             
%             %% Solving for elements
%             % Solving for period
%             T=(1/MM)*3600*24; %seconds per revolution
%             
%             % Solving for semi-major axis
%             a=(((T^2)*mu)/(4*(pi^2)))^(1/3);
%             
%             % Solving for perigee
%             hp=a*(1-eccentricity);
%             
%             %solving for apogee
%             ha=a*(1+eccentricity);
%             
%             Elements=[inclination,eccentricity,ha,hp,a,RA,AP,MA,MM,T];
%             
%             
%         end
        
        function plotOrbit(app,OrbitalE)           
            [Orbit_ECI,~,~] = Elements2Cartesian(app,OrbitalE);

            plot3(app.DViewerPlot,Orbit_ECI(:,1),Orbit_ECI(:,2),Orbit_ECI(:,3))


        end
        
        function TLE = TLEStructExtract(app,NORAD)
            NORADSearch=char(table2array(app.TLEInfoTable.Data(:,2)));
            NORADSearch=string(NORADSearch(:,1:end-1));
            NORADInd=(NORADSearch==NORAD);
            TableLine=table2array(app.TLEInfoTable.Data(NORADInd,2:end));
            TLE.L1_1=TableLine(1);
            TLE.L1_2=TableLine(2);
            TLE.L1_3=TableLine(3);
            TLE.L1_4=TableLine(4);
            TLE.L1_5=TableLine(5);
            TLE.L1_6=TableLine(6);
            TLE.L1_7=TableLine(7);
            TLE.L1_8=TableLine(8);
            TLE.L2_1=TableLine(9);
            TLE.L2_2=TableLine(10);
            TLE.L2_3=TableLine(11);
            TLE.L2_4=TableLine(12);
            TLE.L2_5=TableLine(13);
            TLE.L2_6=TableLine(14);
            TLE.L2_7=TableLine(15);
        end
        
        function OrbitalE = TLE2Elements(~,TLE)
            mu=398618.0; % [km^3/s^2]
            NORAD=char(TLE.L1_1);
            OrbitalE.NORAD=NORAD(1:(end-1));
            OrbitalE.Classification=NORAD(end);
            year_object=char(TLE.L1_2);
            year=str2num(year_object(1:2));
            if year<50
                year=year+2000;
            else
                year=year+1900;
            end
            OrbitalE.year=year;
            OrbitalE.day=str2double(year_object(3:5));
            OrbitalE.Piece=year_object(6);
            OrbitalE.IntDes=TLE.L1_2;
            
            Epoch=char(TLE.L1_3);
            Eyear=str2double(Epoch(1:2));
            if Eyear<50
                Eyear=Eyear+2000;
            else
                Eyear=Eyear+1900;
            end
            OrbitalE.Epochy=Eyear;
            OrbitalE.Epochd=str2num(Epoch(3:end));
            OrbitalE.Epoch=datenum([OrbitalE.Epochy,1,OrbitalE.Epochd,0,0,0]);
            OrbitalE.DMM=TLE.L1_4;
            
            DDMM=char(TLE.L1_5);
            DDMM=['0.' DDMM];
            if DDMM(end-1)=='-'
                DDMM=str2num(DDMM(1:(end-2)))*10^-str2double(DDMM(end));
            else
                DDMM=str2num(DDMM(1:(end-2)))*10^str2double(DDMM(end));
            end
            OrbitalE.DDMM=DDMM;
            
            OrbitalE.Drag=TLE.L1_6;
            
            Enum=char(TLE.L1_8);
            OrbitalE.TLEGen=str2double(Enum(1:3));
            
            OrbitalE.Inclination=str2num(TLE.L2_2);
            OrbitalE.RA=str2num(TLE.L2_3);
            OrbitalE.Eccentricity=(str2num(TLE.L2_4));
            OrbitalE.AP=str2num(TLE.L2_5);
            OrbitalE.MA=str2num(TLE.L2_6);
            OrbitalE.MM=str2num(TLE.L2_7);
            
            T=(1/OrbitalE.MM)*3600*24; %seconds per revolution
            OrbitalE.T=T;
            
            a=(((T^2)*mu)/(4*(pi^2)))^(1/3);
            OrbitalE.a=a;
               
        end
        
        function [Orbit_ECI,Orbit_LLA,r_LLA] = Elements2Cartesian(app,OrbitalE)
             mu = 3.98618e14; % [m3/s2] Earth's geocentric gravitational constant
                    
            a_m = OrbitalE.a*1e3;
            e = OrbitalE.Eccentricity;
            M_deg = OrbitalE.MA;
            
        
            n_rad_per_s = sqrt(mu/a_m^3);  % [rad/s] mean motion
            n_deg_per_s = rad2deg(n_rad_per_s); % [deg/s] mean motion
            M_rad = deg2rad(M_deg);
            E_rad = M_rad;
            dE = 99999;
            eps = 1e-6; % [rad] control precision of Newton's method solution
            while (abs(dE) > eps)
                dE = (E_rad - e * sin(E_rad) - M_rad)/(1 - e * cos(E_rad));
                E_rad = E_rad -  dE;
            end
            p_m = a_m*(cos(E_rad) - e);
            q_m = a_m*sqrt(1 - e^2)*sin(E_rad);
            
%             dMdt_rad_per_s = n_rad_per_s;
%             dEdt_rad_per_s = dMdt_rad_per_s/(1 - e*cos(E_rad));
%             dpdt_m_per_s = -a_m*sin(E_rad)*dEdt_rad_per_s;
%             dqdt_m_per_s = a_m*cos(E_rad)*dEdt_rad_per_s*sqrt(1 - e^2);
            E_deg_epoch = rad2deg(E_rad);
            
            %% *Rotate To ECI*
            Rz_Omega = [ ...
                [cosd(OrbitalE.RA) sind(OrbitalE.RA) 0]; ...
                [-sind(OrbitalE.RA) cosd(OrbitalE.RA) 0]; ...
                [0 0 1]];
            Rx_i = [ ...
                [1 0 0]; ...
                [0 cosd(OrbitalE.Inclination) sind(OrbitalE.Inclination)]; ...
                [0 -sind(OrbitalE.Inclination) cosd(OrbitalE.Inclination)]];
            Rz_omega = [ ...
                [cosd(OrbitalE.AP) sind(OrbitalE.AP) 0]; ...
                [-sind(OrbitalE.AP) cosd(OrbitalE.AP) 0]; ...
                [0 0 1]];
            
            % time of epoch
            [Year,Month,Day,H,M,S] = datevec(OrbitalE.Epoch);
%             HourUTC = H + M/60.0 + S/3600.0;
%             jd = juliandate(Year,Month,Day,HourUTC,0,0);
%             jd0 = juliandate(Year,Month,Day,0,0,0);
            % form time in Julian centuries from J2000
%             T = (jd - 2451545.0d0)./36525.0d0;
            
            
%             Theta_deg = 100.460618375 + 36000.770053608336*T + 0.0003879333*T^2 + 15*H + M/4 + mod(S/240,360);
            
%             Rz_hour = [ ...
%                 [cosd(Theta_deg) sind(Theta_deg) 0]; ...
%                 [-sind(Theta_deg) cosd(Theta_deg) 0]; ...
%                 [0 0 1]];
            
            % position of satellite at epoch in the orbit pq plane
            r_pq = [p_m q_m 0]';
            % position of satellite at epoch in ECI coordinates
            
%             omega_deg = OrbitalE.AP;
%             Omega_deg = OrbitalE.AP;
            i_deg = OrbitalE.Inclination;
            
            r_ECI = inv(Rz_Omega)*inv(Rx_i)*inv(Rz_omega)*r_pq;
            r_LLA = eci2lla(r_ECI',datevec(datenum(Year, Month, Day, H, M, S)),'IAU-2000/2006');
            
            
            if app.CreateDataPacketsButton.Enable=="off"
                PassoverTime=app.PassoverTimesEditField.Value;
                GPSRate=app.GPSClockMHzEditField.Value*1E6;
                SDRRate=app.SDRDataRateMHzEditField.Value*1E6;
%                 Evals = 0:1:(360.0*PassoverTime/(OrbitalE.T*60)); % [deg] values of the eccentric anomaly around orbit
                Evals=linspace(0,(360.0*PassoverTime/(OrbitalE.T*60)),GPSRate*PassoverTime);
            else
                Evals = 0:1:360.0; % [deg] values of the eccentric anomaly around orbit
            end


            Orbit_p = a_m*(cosd(Evals)-e); % [m] orbit positions
            Orbit_q = a_m*sqrt(1 - e^2)*sind(Evals); % [m] orbit positions
            deltaT_s = ((Evals-E_deg_epoch) - e*sind(Evals-E_deg_epoch))/n_deg_per_s; % [s] time since epoch along orbit
            %
            Orbit_ECI = zeros(numel(deltaT_s),3);
            Orbit_LLA = zeros(numel(deltaT_s),3);
            for ipt = 1:size(Orbit_ECI,1)
                r_pq = [Orbit_p(ipt) Orbit_q(ipt) 0]';
                Orbit_ECI(ipt,:) = (inv(Rz_Omega)*inv(Rx_i)*inv(Rz_omega)*r_pq)'; %[Rz_Omega*Rx_i*Rz_omega*r_pq]';
                lla = eci2lla(Orbit_ECI(ipt,:),datevec(datenum(Year, Month, Day, H, M, S+deltaT_s(ipt))),'IAU-2000/2006');
                Orbit_LLA(ipt,:) = lla;
            end
            
            Orbit_ECI=Orbit_ECI/1000;
            Orbit_LLA=Orbit_LLA/1000;
            r_LLA=r_LLA/1000;
        end
        
        function PlotGroundTrack(app,OrbitalE)
            [~,Orbit_LLA,r_LLA] = Elements2Cartesian(app,OrbitalE);
            Orbit_LLA=Orbit_LLA*1000;
            r_LLA=r_LLA*1000;
            
            %                         [Orbit_LLA
            %                             ,r_LLA]=[Orbit_LLA,r_LLA]*1000;
            %% *Plot Cartesian Coordinates*
            %             figure('color','white');
            %             plot3(app.DViewerPlot,Orbit_ECI(:,1),Orbit_ECI(:,2),Orbit_ECI(:,3))
            
            %             xlabel('ECI x [m]');
            %             ylabel('ECI y [m]');
            %             zlabel('ECI z [m]');
            %             title('Satellite Orbit in ECI Coordinates');
            %             grid on
            
            %% *Plot Map*
            %             figure('color','white');
            earth = imread([pwd, '\appResources\groundTrack.jpg']);
            lv= size(earth,1);
            lh= size(earth,2);
            lats =  (1:lv)*180/lv - 90;
            lons =  (1:lh)*360/lh - 180;
            image(app.groundtrackPlot,lons, -lats, earth)
            hold(app.groundtrackPlot, 'on')
            set(app.groundtrackPlot,'ydir','normal');
            grid(app.groundtrackPlot, 'on')
            plot(app.groundtrackPlot,Orbit_LLA(:,2),Orbit_LLA(:,1),'.r');
            plot(app.groundtrackPlot,r_LLA(2),r_LLA(1),'p','MarkerFaceColor',[1 0 0.7],'MarkerEdgeColor','none','Markersize',14);
            set(app.groundtrackPlot,'XTick',(-180:30:180));
            set(app.groundtrackPlot,'YTick',(-90:30:90));
            set(app.groundtrackPlot,'Xcolor',0.3*ones(1,3));
            set(app.groundtrackPlot,'Ycolor',0.3*ones(1,3));
            title(app.groundtrackPlot,['Ground Track for Epoch ' datestr(OrbitalE.Epoch)])
            xlabel(app.groundtrackPlot,'Longitude (degrees)')
            ylabel(app.groundtrackPlot,'Latitude (degrees)')
            axis(app.groundtrackPlot, 'equal')
            axis(app.groundtrackPlot, 'tight')
            set(app.groundtrackPlot,'fontweight','bold');
        end
        
        function resultIND = Search(app)

            Namefrag=app.SearchSatelliteEditField.Value;
            SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
         
            charSatNum=sum(char(SatName)~=' ',2); % Number of characters in each satellite name
            Match=ismember(char(SatName),Namefrag);
            
            weirdIn=(sum(abs(diff(Match,2)),2));
            
            
            charSatMatch=sum(Match,2); % Number of matching characters
            nearMatch=zeros(length(Match),1)
            for i=1:length(Match(:,1))
                nearMatch(i)=sum(diff(find(Match(i,:))))/charSatMatch(i);
            end
            
            nearMatch(nearMatch==0)=nan;
            nearMatch(isnan(nearMatch))=999;
            nearMatch=nearMatch./charSatMatch;
            [~,aIndex]=sort(nearMatch);
            resultIND=SatName(aIndex);
            
            
        end
        
        function UpdateSearchSatelliteField(app)
            resultIND = Search(app);
            %             SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
            app.ListBox.Items=cellstr(resultIND);
        end
        
        
        function ErrorMSG(app,ErrorNum)
            if ErrorNum==1
                uialert(app.TLEMaster,'File not found','Invalid File','Icon','warning');
            elseif ErrorNum==2
                uialert(app.TLEMaster,'File found','Invalid File','Icon','success');
            else
                uialert(app.TLEMaster,'File not found','Invalid File','Icon','success');
                return
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            if isfile([pwd '\appResources\RaytheonLogo.png'])
                app.RaytheonLogo.ImageSource=[pwd '\appResources\RaytheonLogo.png'];
                
            end
             if isfile([pwd '\appResources\CULogo.jpg'])
                app.CULogo.ImageSource=[pwd '\appResources\CULogo.jpg'];
%                 uialert(fig,'File not found','Invalid File','Icon','warning');
             end
%              ErrorMSG(app,1)
%              ErrorMSG(app,2)
%             uialert(app.TLEMaster,'File not found','Invalid File','Icon','success');
            TLEUpdate(app)
            PlotEarth(app)
            UpdateSearchSatelliteField(app)
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            TLEUpdate(app)
        end

        % Button pushed function: PlotEarthAxesButton
        function PlotEarthAxesButtonPushed(app, event)
            PlotEarth(app)
            
        end

        % Button pushed function: PlotCALSPHERE1Button
        function PlotCALSPHERE1ButtonPushed(app, event)
            NORADdes="25544";
            TLE = TLEStructExtract(app,NORADdes);
            OrbitalE = TLE2Elements(app, TLE);
%             Elements = ElementInfo(app,NORADdes);
            plotOrbit(app,OrbitalE)
            
            
        end

        % Value changed function: SElement1
        function SElement1ValueChanged(app, event)
            resultIND = Search(app);
            app.RefinedSearchTable.Data=app.ElementInfoTable.Data(resultIND,:);
            
            app.SearchResultNumTxt.Text=[char(num2str(length(find(resultIND)))) , ' results of ',char(num2str(height(app.ElementInfoTable.Data(:,4)))), ' found'];
            if isempty(app.SElement1.Value)
                UpdateButtonPushed(app);
                
            end
        end

        % Button pushed function: PlotGroundTrackButton
        function PlotGroundTrackButtonPushed(app, event)
             NORADdes="25544";
            TLE = TLEStructExtract(app,NORADdes);
            OrbitalE = TLE2Elements(app, TLE);
%             Elements = ElementInfo(app,NORADdes);
            PlotGroundTrack(app,OrbitalE)
            
            
        end

        % Value changed function: SearchSatelliteEditField
        function SearchSatelliteEditFieldValueChanged(app, event)
           UpdateSearchSatelliteField(app)
        end

        % Button pushed function: ConfirmSelectionButton
        function ConfirmSelectionButtonPushed(app, event)
            app.ConfirmedSatelliteEditField.Value=app.ListBox.Value;
            
            
        end

        % Button pushed function: CreateDataPacketsButton
        function CreateDataPacketsButtonPushed(app, event)
            
            SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
            indexVal=strcmp(app.ConfirmedSatelliteEditField.Value,SatName);
            %             app.TLEInfoTable
            
            
            NORADdes=string(table2cell(app.ElementInfoTable.Data(indexVal,2)));
            TLE = TLEStructExtract(app,NORADdes);
            OrbitalE = TLE2Elements(app, TLE);
            %             Elements = ElementInfo(app,NORADdes);
            
            
            [Orbit_ECI,Orbit_LLA,r_LLA]=Elements2Cartesian(app,OrbitalE);
            plotOrbit(app,OrbitalE)


        end

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            filename=uigetfile([pwd '\TDOA Data Sets\']);
            app.EditField.Value=[[pwd '\TDOA Data Sets\' filename]]


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TLEMaster = uifigure('Visible', 'off');
            app.TLEMaster.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.TLEMaster);
            app.DATAManipulationTab = uitab(app.TabGroup);
            app.DATAManipulationTab.Title = 'DATA Manipulation';
            app.EstimatedDatasizeEditFieldLabel = uilabel(app.DATAManipulationTab);
            app.EstimatedDatasizeEditFieldLabel.Enable = 'off';
            app.EstimatedDatasizeEditFieldLabel.Text = 'Estimated Data size';
            app.EstimatedDatasizeEditField = uieditfield(app.DATAManipulationTab, 'text');
            app.EstimatedDatasizeEditField.Editable = 'off';
            app.EstimatedDatasizeEditField.Enable = 'off';
            app.CreateDataPacketsButton = uibutton(app.DATAManipulationTab, 'push');
            app.CreateDataPacketsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDataPacketsButtonPushed, true);
            app.CreateDataPacketsButton.Text = 'Create Data Packets';
            app.Panel = uipanel(app.DATAManipulationTab);
            app.Panel.Title = 'Panel';
            app.PassoverTimesEditFieldLabel = uilabel(app.Panel);
            app.PassoverTimesEditFieldLabel.Text = 'PassoverTime(s)';
            app.PassoverTimesEditField = uieditfield(app.Panel, 'numeric');
            app.PassoverTimesEditField.Value = 1;
            app.SDRDataRateMHzEditFieldLabel = uilabel(app.Panel);
            app.SDRDataRateMHzEditFieldLabel.Text = 'SDR Data Rate(MHz)';
            app.SDRDataRateMHzEditField = uieditfield(app.Panel, 'numeric');
            app.GPSClockMHzEditFieldLabel = uilabel(app.Panel);
            app.GPSClockMHzEditFieldLabel.Text = 'GPS Clock(MHz)';
            app.GPSClockMHzEditField = uieditfield(app.Panel, 'numeric');
            app.CULogo = uiimage(app.DATAManipulationTab);
            app.RaytheonLogo = uiimage(app.DATAManipulationTab);
            app.ListBox = uilistbox(app.DATAManipulationTab);
            app.SearchSatelliteEditFieldLabel = uilabel(app.DATAManipulationTab);
            app.SearchSatelliteEditFieldLabel.Text = 'Search Satellite';
            app.SearchSatelliteEditField = uieditfield(app.DATAManipulationTab, 'text');
            app.SearchSatelliteEditField.ValueChangedFcn = createCallbackFcn(app, @SearchSatelliteEditFieldValueChanged, true);
            app.SearchSatelliteEditField.Value = 'CSIM';
            app.ConfirmedSatelliteEditFieldLabel = uilabel(app.DATAManipulationTab);
            app.ConfirmedSatelliteEditFieldLabel.Text = 'Confirmed Satellite';
            app.ConfirmedSatelliteEditField = uieditfield(app.DATAManipulationTab, 'text');
            app.ConfirmSelectionButton = uibutton(app.DATAManipulationTab, 'push');
            app.ConfirmSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSelectionButtonPushed, true);
            app.ConfirmSelectionButton.Text = 'Confirm Selection';
            app.V11Label = uilabel(app.DATAManipulationTab);
            app.V11Label.Enable = 'off';
            app.V11Label.Text = 'V 1.1';
            app.TLETab = uitab(app.TabGroup);
            app.TLETab.Title = 'TLE';
            app.UpdateButton = uibutton(app.TLETab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TLEfilename = uieditfield(app.TLETab, 'text');
            app.TLEfilename.Editable = 'off';
            app.TLEfilename.Enable = 'off';
            app.TLEfilename.Value = 'SATINFO_TLE.txt';
            app.DatafromEditFieldLabel = uilabel(app.TLETab);
            app.DatafromEditFieldLabel.Enable = 'off';
            app.DatafromEditFieldLabel.Text = 'Data from: ';
            app.DatafromEditField = uieditfield(app.TLETab, 'text');
            app.DatafromEditField.Editable = 'off';
            app.DatafromEditField.Enable = 'off';
            app.DatafromEditField.Value = 'https://www.celestrak.com/NORAD/elements/active.txt';
            app.NetworkConnectionLabel = uilabel(app.TLETab);
            app.NetworkConnectionLabel.Enable = 'off';
            app.NetworkConnectionLabel.Text = 'Network Connection:';
            app.NetworkConnectionLamp = uilamp(app.TLETab);
            app.GPSCoordLabel = uilabel(app.TLETab);
            app.GPSCoordLabel.Text = 'GPS Coord:';
            app.GPSCoordEditField = uieditfield(app.TLETab, 'text');
            app.GPSCoordEditField.Value = '40.0510522,-105.2290141';
            app.TableTab = uitabgroup(app.TLETab);
            app.OrbitalElementsTab = uitab(app.TableTab);
            app.OrbitalElementsTab.Title = 'Orbital Elements';
            app.ElementInfoTable = uitable(app.OrbitalElementsTab);
            app.ElementInfoTable.ColumnName = {'Name'; 'NORAD'; 'Int. D'; 'a[km]'; 'Period[s]'; 'Inc.[deg]'; 'Eccentricity'; 'Right Ascension[deg]'; 'Arg. of Perigee[deg]'; 'Mean Anomaly'};
            app.ElementInfoTable.RowName = {};
            app.TLE_tab = uitab(app.TableTab);
            app.TLE_tab.Title = 'TLE';
            app.TLEInfoTable = uitable(app.TLE_tab);
            app.TLEInfoTable.ColumnName = {'Name'; '1-1'; '1-2'; '1-3'; '1-4'; '1-5'; '1-6'; '1-7'; '1-8'; '2-1'; '2-2'; '2-3'; '2-4'; '2-5'; '2-6'; '2-7'};
            app.TLEInfoTable.RowName = {};
            app.SearchTab = uitab(app.TableTab);
            app.SearchTab.Title = 'Search';
            app.SearchElement1Label = uilabel(app.SearchTab);
            app.SearchElement1Label.Text = 'Search Element 1';
            app.SElement1 = uieditfield(app.SearchTab, 'text');
            app.SElement1.ValueChangedFcn = createCallbackFcn(app, @SElement1ValueChanged, true);
            app.SearchElement2EditFieldLabel = uilabel(app.SearchTab);
            app.SearchElement2EditFieldLabel.Text = 'Search Element 2';
            app.SElement2 = uieditfield(app.SearchTab, 'text');
            app.RefinedSearchTable = uitable(app.SearchTab);
            app.RefinedSearchTable.ColumnName = {'Name'; 'NORAD'; 'Int. D'; 'a[km]'; 'Period[s]'; 'Inc.[deg]'; 'Eccentricity'; 'Right Ascension[deg]'; 'Arg. of Perigee[deg]'; 'Mean Anomaly'};
            app.RefinedSearchTable.RowName = {};
            app.SearchResultNumTxt = uilabel(app.SearchTab);
            app.SearchResultNumTxt.Enable = 'off';
            app.SearchResultNumTxt.Text = '# of # Results';
            app.TotalResultsTxt = uilabel(app.TLETab);
            app.TotalResultsTxt.Enable = 'off';
            app.TotalResultsTxt.Text = ' # Results';
            app.GroundTrackTab_2 = uitab(app.TabGroup);
            app.GroundTrackTab_2.Title = 'Ground Track';
            app.PlotGroundTrackButton = uibutton(app.GroundTrackTab_2, 'push');
            app.PlotGroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundTrackButtonPushed, true);
            app.PlotGroundTrackButton.Text = 'Plot GroundTrack';
            app.groundtrackPlot = uiaxes(app.GroundTrackTab_2);
            title(app.groundtrackPlot, 'Title')
            xlabel(app.groundtrackPlot, 'X')
            ylabel(app.groundtrackPlot, 'Y')
            zlabel(app.groundtrackPlot, 'Z')
            app.OrbitViewerTab = uitab(app.TabGroup);
            app.OrbitViewerTab.Title = 'Orbit Viewer';
            app.PlotEarthAxesButton = uibutton(app.OrbitViewerTab, 'push');
            app.PlotEarthAxesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEarthAxesButtonPushed, true);
            app.PlotEarthAxesButton.Text = 'Plot Earth/Axes';
            app.PlotCALSPHERE1Button = uibutton(app.OrbitViewerTab, 'push');
            app.PlotCALSPHERE1Button.ButtonPushedFcn = createCallbackFcn(app, @PlotCALSPHERE1ButtonPushed, true);
            app.PlotCALSPHERE1Button.Text = 'Plot CALSPHERE 1';
            app.Button = uibutton(app.OrbitViewerTab, 'push');
            app.DViewerPlot = uiaxes(app.OrbitViewerTab);
            title(app.DViewerPlot, 'Title')
            xlabel(app.DViewerPlot, 'X')
            ylabel(app.DViewerPlot, 'Y')
            app.MonteCarloTab = uitab(app.TabGroup);
            app.MonteCarloTab.Title = 'Monte Carlo';
            app.IntegrateErrorButton = uibutton(app.MonteCarloTab, 'push');
            app.IntegrateErrorButton.Text = 'Integrate Error';
            app.FileNameEditFieldLabel = uilabel(app.MonteCarloTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.MonteCarloTab, 'text');
            app.EditField = uieditfield(app.MonteCarloTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.LoadDataFileButton = uibutton(app.MonteCarloTab, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.Tree = uitree(app.MonteCarloTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.GibbsMethodTab = uitab(app.TabGroup);
            app.GibbsMethodTab.Title = 'Gibbs Method';
            app.ContextMenu = uicontextmenu(app.TLEMaster);
            % Assign app.ContextMenu
            app.SElement1.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.ContextMenu);
            app.Menu3.Text = 'Menu3';
            % Show the figure after all components are created
            app.TLEMaster.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TLE_DATA_V_1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TLEMaster)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TLEMaster)
        end
    end
end