classdef TLE_DATA_V_1_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TLEMaster                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TruthDataTab                    matlab.ui.container.Tab
        EstimatedDatasizeEditFieldLabel  matlab.ui.control.Label
        EstimatedDatasizeEditField      matlab.ui.control.EditField
        CreateDataPacketsButton         matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        PassoverTimesEditFieldLabel     matlab.ui.control.Label
        PassoverTimesEditField          matlab.ui.control.NumericEditField
        SDRDataRateMHzEditFieldLabel    matlab.ui.control.Label
        SDRDataRateMHzEditField         matlab.ui.control.NumericEditField
        GPSClockMHzEditFieldLabel       matlab.ui.control.Label
        GPSClockMHzEditField            matlab.ui.control.NumericEditField
        CULogo                          matlab.ui.control.Image
        RaytheonLogo                    matlab.ui.control.Image
        ListBox                         matlab.ui.control.ListBox
        SearchSatelliteEditFieldLabel   matlab.ui.control.Label
        SearchSatelliteEditField        matlab.ui.control.EditField
        ConfirmedSatelliteEditFieldLabel  matlab.ui.control.Label
        ConfirmedSatelliteEditField     matlab.ui.control.EditField
        ConfirmSelectionButton          matlab.ui.control.Button
        V17Label                        matlab.ui.control.Label
        SendasTLEReferenceButton        matlab.ui.control.Button
        TLECalcTab                      matlab.ui.container.Tab
        FileLocationAddress             matlab.ui.control.EditField
        FilePreparationPanel            matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        LoadDataFileButton              matlab.ui.control.Button
        MonteCarloPanel                 matlab.ui.container.Panel
        TDoAErrorkmEditField            matlab.ui.control.NumericEditField
        TDoAErrorkmEditFieldLabel       matlab.ui.control.Label
        ApplyMonteCarloButton           matlab.ui.control.Button
        DistributionButtonGroup         matlab.ui.container.ButtonGroup
        NormalButton                    matlab.ui.control.RadioButton
        UniformButton                   matlab.ui.control.RadioButton
        DataFileNameEditFieldLabel      matlab.ui.control.Label
        DataFileNameEditField           matlab.ui.control.EditField
        DataRestrictionsPanel           matlab.ui.container.Panel
        ApplyRestrictionsButton         matlab.ui.control.Button
        PassoverLengthsLabel            matlab.ui.control.Label
        PassoverLengthsEditField        matlab.ui.control.NumericEditField
        SDRDataRateMHzLabel             matlab.ui.control.Label
        SDRDataRateMHzEditField_2       matlab.ui.control.NumericEditField
        GPSClockMHzLabel                matlab.ui.control.Label
        GPSClockMHzEditField_2          matlab.ui.control.NumericEditField
        PingRateLabel                   matlab.ui.control.Label
        PingRateEditField               matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        TLEComparisonPanel              matlab.ui.container.Panel
        TabGroup4                       matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        AVGSpreadEditFieldLabel         matlab.ui.control.Label
        AVGSpreadEditField              matlab.ui.control.NumericEditField
        OffsetfromCenterEditFieldLabel  matlab.ui.control.Label
        OffsetfromCenterEditField       matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        CoplanarValueEditFieldLabel     matlab.ui.control.Label
        CoplanarValueEditField          matlab.ui.control.EditField
        accuratewhenclosetozeroLabel    matlab.ui.control.Label
        TLEincludedinDatafileCheckBox   matlab.ui.control.CheckBox
        VelocityTimeSeriesincludedinDatafileCheckBox  matlab.ui.control.CheckBox
        SensitivityAnalysisTDoAAccuracyTab  matlab.ui.container.Tab
        SaveFigureButton                matlab.ui.control.Button
        PassoverLengthButtonGroup       matlab.ui.container.ButtonGroup
        MaximizeButton                  matlab.ui.control.RadioButton
        SingleSettingsButton            matlab.ui.control.RadioButton
        TDoAAccSettingsPanel            matlab.ui.container.Panel
        DataPointsEditFieldLabel        matlab.ui.control.Label
        DataPointsEditField             matlab.ui.control.NumericEditField
        ErrorMinEditFieldLabel          matlab.ui.control.Label
        ErrorMinEditField               matlab.ui.control.NumericEditField
        ErrorMaxEditFieldLabel          matlab.ui.control.Label
        ErrorMaxEditField               matlab.ui.control.NumericEditField
        RunAnalysisButton               matlab.ui.control.Button
        TDoASensitivityPlot             matlab.ui.control.UIAxes
        SensitivityAnalysisPassLengthTab  matlab.ui.container.Tab
        SaveFigureButton_Pass           matlab.ui.control.Button
        PassLengthSettingsPanel         matlab.ui.container.Panel
        DataPointsEditField_2Label      matlab.ui.control.Label
        DataPointsEditField_Pass        matlab.ui.control.NumericEditField
        MinSpreadEditFieldLabel         matlab.ui.control.Label
        MinSpreadEditField              matlab.ui.control.NumericEditField
        MaxSpreadEditFieldLabel         matlab.ui.control.Label
        MaxSpreadEditField              matlab.ui.control.NumericEditField
        RunAnalysisButton_Pass          matlab.ui.control.Button
        TDoASettingsPanel               matlab.ui.container.Panel
        TDoAErrorkmEditField_2Label     matlab.ui.control.Label
        TDoAErrorkmEditField_2          matlab.ui.control.NumericEditField
        PassoverSensitivityPlot         matlab.ui.control.UIAxes
        TruthTLEPanel                   matlab.ui.container.Panel
        TRef_Name                       matlab.ui.control.EditField
        TL1_1                           matlab.ui.control.EditField
        TL1_2                           matlab.ui.control.EditField
        TL1_3                           matlab.ui.control.EditField
        TL1_4                           matlab.ui.control.EditField
        TL1_5                           matlab.ui.control.EditField
        TL1_6                           matlab.ui.control.EditField
        TL1_7                           matlab.ui.control.EditField
        TL1_8                           matlab.ui.control.EditField
        TL2_1                           matlab.ui.control.EditField
        TL2_2                           matlab.ui.control.EditField
        TL2_3                           matlab.ui.control.EditField
        TL2_4                           matlab.ui.control.EditField
        TL2_5                           matlab.ui.control.EditField
        TL2_6                           matlab.ui.control.EditField
        TL2_7                           matlab.ui.control.EditField
        ErrorEditFieldLabel             matlab.ui.control.Label
        ErrorEditField                  matlab.ui.control.EditField
        CalculatedTLEPanel              matlab.ui.container.Panel
        CL1_1                           matlab.ui.control.EditField
        CL1_2                           matlab.ui.control.EditField
        CL2_1                           matlab.ui.control.EditField
        CRef_Name                       matlab.ui.control.EditField
        CL2_2                           matlab.ui.control.EditField
        CL1_3                           matlab.ui.control.EditField
        CL2_3                           matlab.ui.control.EditField
        CL1_4                           matlab.ui.control.EditField
        CL2_4                           matlab.ui.control.EditField
        CL1_5                           matlab.ui.control.EditField
        CL2_5                           matlab.ui.control.EditField
        CL1_6                           matlab.ui.control.EditField
        CL2_6                           matlab.ui.control.EditField
        CL1_7                           matlab.ui.control.EditField
        CL2_7                           matlab.ui.control.EditField
        CL1_8                           matlab.ui.control.EditField
        CalcErrorButton                 matlab.ui.control.Button
        Reset                           matlab.ui.control.Button
        BUTTONButton                    matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        TLETab                          matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        TLEfilename                     matlab.ui.control.EditField
        DatafromEditFieldLabel          matlab.ui.control.Label
        DatafromEditField               matlab.ui.control.EditField
        NetworkConnectionLabel          matlab.ui.control.Label
        NetworkConnectionLamp           matlab.ui.control.Lamp
        GPSCoordLabel                   matlab.ui.control.Label
        GPSCoordEditField               matlab.ui.control.EditField
        TableTab                        matlab.ui.container.TabGroup
        OrbitalElementsTab              matlab.ui.container.Tab
        ElementInfoTable                matlab.ui.control.Table
        TLE_tab                         matlab.ui.container.Tab
        TLEInfoTable                    matlab.ui.control.Table
        SearchTab                       matlab.ui.container.Tab
        SearchElement1Label             matlab.ui.control.Label
        SElement1                       matlab.ui.control.EditField
        SearchElement2EditFieldLabel    matlab.ui.control.Label
        SElement2                       matlab.ui.control.EditField
        RefinedSearchTable              matlab.ui.control.Table
        SearchResultNumTxt              matlab.ui.control.Label
        TotalResultsTxt                 matlab.ui.control.Label
        OrbitViewerTab                  matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        DOrbitTab                       matlab.ui.container.Tab
        DViewerPlot                     matlab.ui.control.UIAxes
        GroundTrackTab_3                matlab.ui.container.Tab
        groundtrackPlot                 matlab.ui.control.UIAxes
        DisplayInformationPanel         matlab.ui.container.Panel
        CurrentlyDisplayedEditFieldLabel  matlab.ui.control.Label
        CurrentlyDisplayedEditField     matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        CInclination                    matlab.ui.container.ContextMenu
        InclinationdegMenu              matlab.ui.container.Menu
        RelativeErrorNAMenu             matlab.ui.container.Menu
        CRightAscension                 matlab.ui.container.ContextMenu
        RightAscensiondegMenu           matlab.ui.container.Menu
        RelativeErrorNAMenu_2           matlab.ui.container.Menu
        CEccentricity                   matlab.ui.container.ContextMenu
        EccentricityMenu                matlab.ui.container.Menu
        RelativeErrorNAMenu_3           matlab.ui.container.Menu
        CArgumentofPerigee              matlab.ui.container.ContextMenu
        ArguementofPerigeedegMenu       matlab.ui.container.Menu
        RelativeErrorNAMenu_4           matlab.ui.container.Menu
        CMeanAnomaly                    matlab.ui.container.ContextMenu
        MeanAnomalydegMenu              matlab.ui.container.Menu
        RelativeErrorNAMenu_5           matlab.ui.container.Menu
        CMeanMotion                     matlab.ui.container.ContextMenu
        MeanMotionrevdayMenu            matlab.ui.container.Menu
        RelativeErrorNAMenu_6           matlab.ui.container.Menu
        TCatalognum_Classification      matlab.ui.container.ContextMenu
        CatalogNumberClassificationMenu  matlab.ui.container.Menu
        TSattelliteName                 matlab.ui.container.ContextMenu
        SatelliteNameMenu               matlab.ui.container.Menu
        TIntDesignator                  matlab.ui.container.ContextMenu
        InternationalDesignatorMenu     matlab.ui.container.Menu
        TEpoch                          matlab.ui.container.ContextMenu
        EpochdayandyearMenu             matlab.ui.container.Menu
        TFirstDerivative                matlab.ui.container.ContextMenu
        FirstDerivativeofMeanMotionMenu  matlab.ui.container.Menu
        TSecondDerivative               matlab.ui.container.ContextMenu
        SecondDerivativeofMeanMotionMenu  matlab.ui.container.Menu
        Tdrag                           matlab.ui.container.ContextMenu
        DragTermMenu                    matlab.ui.container.Menu
        TEphemeris                      matlab.ui.container.ContextMenu
        EphemeristypeMenu               matlab.ui.container.Menu
        TElement                        matlab.ui.container.ContextMenu
        ElementSetNumberandChecksumMenu  matlab.ui.container.Menu
        TSatelliteCatalog               matlab.ui.container.ContextMenu
        SatelliteCatalognumberMenu      matlab.ui.container.Menu
        ContextMenu                     matlab.ui.container.ContextMenu
        ForMaximumpossibleputin100Menu  matlab.ui.container.Menu
    end

    
    methods (Access = private)
        
        function info=TLEextract(~,tlines,k)
            dataL=char(tlines);
            if ~isempty(dataL)
                LOCInd=[find(dataL==' ')' ;length(dataL)];
                LOCInd=[LOCInd(diff(LOCInd)>1) ;length(dataL)];
                info=dataL(LOCInd(k):LOCInd(k+1));
                info=string(info(info~=' '));
            else
                info="";
            end
        end
        
        function TLEUpdate(app)
            
            WebAddress=app.DatafromEditField.Value;
            [data,status] = urlread(WebAddress);
            if status==1
                txtName=[pwd '\appResources\TLE_Archive_',char(datetime('today','Format','MM-d-y')),'.txt'];


                %                  FolderDelete=dir([pwd, '\appResources\']);
                %                 FolderDelete={FolderDelete.name};
                %                  FolderDelete=string(FolderDelete(3:end));
                %                 FolderDelete=string([pwd '\appResources\',FolderDelete]);
                %                 FolderDelete=FolderDelete(contains(FolderDelete,"TLE_",'IgnoreCase',true));
                %                 for k=1:length(FolderDelete)
                %                     delete([pwd '\appResources\',char(FolderDelete(k))])
                %                 end
                
                dlmwrite(txtName,data)
                app.NetworkConnectionLamp.Color=[0 1 0];
            else
                files=dir([pwd, '\appResources\']);
                files={files.name};
                files=string(files(3:end));
                %                 MatchInd=zeros(length(files),1);
                MatchInd=contains(files,"TLE_",'IgnoreCase',true);
                %                 for j=1:length(files)
                % %                     MatchInd(j)=(~isempty(strfind(files(j),'TLE_')));
                % %                     MatchInd(j)=contains('TLE_',files(j));
                %                 MatchInd(j)=contains(files(j),"TLE_",'IgnoreCase',true)
                %                 end
                MatchInd=find(MatchInd);
                if isempty(MatchInd)
                    return
                end
                
                fileN=char(files( MatchInd(1)));
                txtName=[pwd,'\appResources\',fileN];
                app.NetworkConnectionLamp.Color=[1 0 0];
                uialert(app.TLEMaster,['Network could not be found.                                                     TLE data used from:',fileN(13:end)],'Invalid Network','Icon','warning');
            end
            
            fid=fopen(txtName);
            tline = fgetl(fid);
            tlines=cell(ceil(length(data)/27),1);
            tlines(:,:)={''};
            g=0;
            while ischar(tline)
                g=g+1;
                tlines(g,1) = {tline};
                tline = fgetl(fid);
            end
            fclose(fid);
            
            tlines=string(tlines);
            tlines=tlines(tlines~=",");
            for i=1:length(tlines)
                tchar=char(tlines(i));
                tlines(i)=string(tchar(tchar~=','));
            end
            NameIND=(1:3:length(tlines))';
            NameIND=NameIND(1:(end-1));
            SatNAME=tlines(NameIND);
            SatNAME=SatNAME(SatNAME~="");
            
            V1=strings(length(NameIND),8);
            V2=strings(length(NameIND),7);
            for j=1:(length(NameIND)-2)
                for h=1:8
                    V1(j,h)= TLEextract(app,tlines(NameIND(j)+1),h);
                end
                for h=1:7
                    V2(j,h)= TLEextract(app,tlines(NameIND(j)+2),h);
                end
            end
            [a,~]=find(V2~="");
            V1=V1(unique(a),:);
            V2=V2(unique(a),:);
            % Concatenates line 2 column 8
            eightNineTen=char(V2(:,7));
            V2(:,7)=string(eightNineTen(:,1:11));
            V2(:,4)=strcat("0.",V2(:,4));
            TLEInfoT=table(SatNAME,V1(:,1),V1(:,2),V1(:,3),V1(:,4),V1(:,5),V1(:,6),V1(:,7),V1(:,8),V2(:,1),V2(:,2),V2(:,3),V2(:,4),V2(:,5),V2(:,6),V2(:,7));
            app.TLEInfoTable.Data=TLEInfoT;


            mu=398618.0; % [km^3/s^2]
            MM=str2num(char(V2(:,7)));
            
            T=(1./MM).*3600.*24; %seconds per revolution
            a=(((T.^2)*mu)/(4*(pi^2))).^(1/3);
            
            
            OrbitalETable=table(SatNAME,V2(:,1),V1(:,2),a,T,V2(:,2),V2(:,4),V2(:,3),V2(:,5),V2(:,6));
            app.ElementInfoTable.Data=OrbitalETable;
            app.RefinedSearchTable.Data=OrbitalETable;
            app.TotalResultsTxt.Text=[char(num2str(length(V2(:,4)))) ,' Active Satellites'];
            app.SearchResultNumTxt.Text=[char(num2str(length(V2(:,4)))) , ' results of ',char(num2str(length(V2(:,4)))), ' found'];
            
        end
        
        function gpsCoord = gpsExtract(app)
            gpsCoord=char(app.GPSCoordEditField.Value);
            splitIND=(gpsCoord==',');
            LatCoord= str2num(gpsCoord(1:(splitIND(1)-1))); %#ok<*ST2NM>
            LongCoord=str2num(gpsCoord((splitIND(1)+1):end)); %#ok<*ST2NM>
            gpsCoord=[LatCoord, LongCoord];
        end
        
        function PlotEarth(app)
            hold(app.DViewerPlot,'off')
            cla(app.DViewerPlot,'reset')
            hold(app.DViewerPlot,'on')
            r = 6371; %km
            [X,Y,Z] = sphere(app.DViewerPlot);
            
            X2=X*r;
            Y2=Y*r;
            Z2=Z*r;
            surf(app.DViewerPlot,X2,Y2,Z2)
            %             warp(app.DViewerPlot,X2,Y2,Z2)
            %             hold(app.DViewerPlot,'on')
            %             plot3(app.DViewerPlot,[0 5],[0 0],'LineWidth',5) %
            lm=8;
            xlim(app.DViewerPlot,[-lm*r,lm*r])
            ylim(app.DViewerPlot,[-lm*r,lm*r])
            zlim(app.DViewerPlot,[-lm*r,lm*r])


            gpsCoord = gpsExtract(app);
            rectGPS = gpsConvert(app,r,gpsCoord(2),gpsCoord(1));
            scatter3(app.DViewerPlot,rectGPS(1),rectGPS(2),rectGPS(3),50,'filled','r')
            
            phi1=90;
            theta1=0;
            zeroLine=[Spherical2Cart(app,r,theta1,phi1);5*Spherical2Cart(app,r,theta1,phi1)];
            plot3(app.DViewerPlot,zeroLine(:,1),zeroLine(:,2),zeroLine(:,3))
            
            GLine=[gpsConvert(app,r,theta1,0);5*gpsConvert(app,r,theta1,0)];
            plot3(app.DViewerPlot,GLine(:,1),GLine(:,2),GLine(:,3))
            
            
            gpsCoord =[49.2577355,-123.123904];%Vancouver
            rectGPS = gpsConvert(app,r,gpsCoord(2),gpsCoord(1));
            scatter3(app.DViewerPlot,rectGPS(1),rectGPS(2),rectGPS(3),50,'filled','b')
            
            legend(app.DViewerPlot,["Earth","GPS Coord","Zero","Greenwich","Vancouver"])
            title(app.DViewerPlot,'Satellite')
            xlabel(app.DViewerPlot,'km')
            ylabel(app.DViewerPlot,'km')
            grid(app.DViewerPlot, 'on')
            view(app.DViewerPlot,[30,30,40])
        end
        function gpsConvert=gpsConvert(app,r,theta,phi)
            % Conversion from gps to sphereical coordinates
            phiAdj=phi-90;
            %Longitude adjust due to time of day
            %             thetaAdj=
            [timeMAT,~] = timeExtract(app,'Europe/London');
            thetaOFF=(timeMAT(1)*3600+timeMAT(2)*60+timeMAT(3))*360/86164;
            thetaAdj=thetaOFF+theta+180;
            %             23 56 4 time to rotate relative to distant stars(86164 seconds)
            gpsConvert = Spherical2Cart(app,r,thetaAdj,phiAdj);
            
        end
        
        function rectCoord = Spherical2Cart(~,r,theta,phi)
            x=r*sind(phi)*cosd(theta);
            y=r*sind(phi)*sind(theta);
            z=r*cosd(phi);
            rectCoord=[x,y,z];
        end
        
        function [timeMAT,dateMAT] = timeExtract(~,timeZone)
            %For local time put 'local'
            %             t = datetime('now','TimeZone','Europe/London','Format','MM-d-y HH.mm.ss.ms');
            %             t = datetime('now','TimeZone','local','Format','MM-d-y HH.mm.ss.ms');
            t = datetime('now','TimeZone',timeZone,'Format','MM-d-y HH.mm.ss.ms');
            charT=char(t);
            splitInd=find(charT==' ');
            dateChar=charT(1:(splitInd-1));
            timeChar=charT((splitInd+1):end);
            hour=str2double(timeChar(1:2));
            minute=str2double(timeChar(4:5));
            second=str2double(timeChar(7:8));
            milisecond=str2double(timeChar(10:end));
            timeMAT=[hour,minute,second,milisecond];
            month=str2double(dateChar(1:2));
            day=str2double(dateChar(4:5));
            year=str2double(dateChar(7:end));
            dateMAT=[day,month,year];
        end
        
        %         function Elements = ElementInfo(app,NORADdes)
        %             % Constants
        %             M_earth=5.9722E24; % [kg]
        %             G=6.67408E-11; %gravitational constant
        %             mu=G*M_earth ;% gravitational constant(M*G) forEarth
        %
        %
        %             %% Getting data from table
        %             rowIND=(string(table2cell(app.TLEInfoTable.Data(:,2)))==NORADdes);
        %             satData=table2cell(app.TLEInfoTable.Data(rowIND,:));
        %             %             inclination=str2num(string(satData(4))); % inclination(deg)
        %             RA=str2num(string(satData(5))); % Right Ascension of the ascending node(deg)
        %             eccentricity=str2num(string(satData(6)));
        %             AP=str2num(string(satData(7))); % Arguement of Perigee
        %             MA=str2num(string(satData(8))); % Mean anomaly
        %             MM=str2num(string(satData(9))); % Mean motion (rev/d)
        %
        %             %% Solving for elements
        %             % Solving for period
        %             T=(1/MM)*3600*24; %seconds per revolution
        %
        %             % Solving for semi-major axis
        %             a=(((T^2)*mu)/(4*(pi^2)))^(1/3);
        %
        %             % Solving for perigee
        %             hp=a*(1-eccentricity);
        %
        %             %solving for apogee
        %             ha=a*(1+eccentricity);
        %
        %             Elements=[inclination,eccentricity,ha,hp,a,RA,AP,MA,MM,T];
        %
        %
        %         end
        
        function plotOrbit(app,OrbitalE)
            [Orbit_ECI,~,~] = Elements2Cartesian(app,OrbitalE);
            
            plot3(app.DViewerPlot,Orbit_ECI(:,1),Orbit_ECI(:,2),Orbit_ECI(:,3))


        end
        
        function TLE = TLEStructExtract(app,NORAD)
            NORADSearch=char(table2array(app.TLEInfoTable.Data(:,2)));
            NORADSearch=string(NORADSearch(:,1:end-1));
            NORADInd=(NORADSearch==NORAD);
            TableLine=table2array(app.TLEInfoTable.Data(NORADInd,2:end));
            TLE.L1_1=TableLine(1);
            TLE.L1_2=TableLine(2);
            TLE.L1_3=TableLine(3);
            TLE.L1_4=TableLine(4);
            TLE.L1_5=TableLine(5);
            TLE.L1_6=TableLine(6);
            TLE.L1_7=TableLine(7);
            TLE.L1_8=TableLine(8);
            TLE.L2_1=TableLine(9);
            TLE.L2_2=TableLine(10);
            TLE.L2_3=TableLine(11);
            TLE.L2_4=TableLine(12);
            TLE.L2_5=TableLine(13);
            TLE.L2_6=TableLine(14);
            TLE.L2_7=TableLine(15);
        end
        
        function OrbitalE = TLE2Elements(~,TLE)
            mu=398618.0; % [km^3/s^2]
            NORAD=char(TLE.L1_1);
            OrbitalE.NORAD=NORAD(1:(end-1));
            OrbitalE.Classification=NORAD(end);
            year_object=char(TLE.L1_2);
            year=str2num(year_object(1:2));
            if year<50
                year=year+2000;
            else
                year=year+1900;
            end
            OrbitalE.year=year;
            OrbitalE.day=str2double(year_object(3:5));
            OrbitalE.Piece=year_object(6);
            OrbitalE.IntDes=TLE.L1_2;
            
            Epoch=char(TLE.L1_3);
            Eyear=str2double(Epoch(1:2));
            if Eyear<50
                Eyear=Eyear+2000;
            else
                Eyear=Eyear+1900;
            end
            OrbitalE.Epochy=Eyear;
            OrbitalE.Epochd=str2num(Epoch(3:end));
            OrbitalE.Epoch=datenum([OrbitalE.Epochy,1,OrbitalE.Epochd,0,0,0]);
            OrbitalE.DMM=TLE.L1_4;
            
            DDMM=char(TLE.L1_5);
            DDMM=['0.' DDMM];
            if DDMM(end-1)=='-'
                DDMM=str2num(DDMM(1:(end-2)))*10^-str2double(DDMM(end));
            else
                DDMM=str2num(DDMM(1:(end-2)))*10^str2double(DDMM(end));
            end
            OrbitalE.DDMM=DDMM;
            
            OrbitalE.Drag=TLE.L1_6;
            
            Enum=char(TLE.L1_8);
            OrbitalE.TLEGen=str2double(Enum(1:3));
            
            OrbitalE.Inclination=str2num(TLE.L2_2);
            OrbitalE.RA=str2num(TLE.L2_3);
            OrbitalE.Eccentricity=(str2num(TLE.L2_4));
            OrbitalE.AP=str2num(TLE.L2_5);
            OrbitalE.MA=str2num(TLE.L2_6);
            OrbitalE.MM=str2num(TLE.L2_7);
            
            T=(1/OrbitalE.MM)*3600*24; %seconds per revolution
            OrbitalE.T=T;
            
            a=(((T^2)*mu)/(4*(pi^2)))^(1/3);
            OrbitalE.a=a;
            
        end
        
        function [Orbit_ECI,Orbit_LLA,r_LLA] = Elements2Cartesian(app,OrbitalE)
            mu = 3.98618e14; % [m3/s2] Earth's geocentric gravitational constant
            
            a_m = OrbitalE.a*1e3;
            e = OrbitalE.Eccentricity;
            M_deg = OrbitalE.MA;
            
            
            n_rad_per_s = sqrt(mu/a_m^3);  % [rad/s] mean motion
            n_deg_per_s = rad2deg(n_rad_per_s); % [deg/s] mean motion
            M_rad = deg2rad(M_deg);
            E_rad = M_rad;
            dE = 99999;
            eps = 1e-6; % [rad] control precision of Newton's method solution
            while (abs(dE) > eps)
                dE = (E_rad - e * sin(E_rad) - M_rad)/(1 - e * cos(E_rad));
                E_rad = E_rad -  dE;
            end
            p_m = a_m*(cos(E_rad) - e);
            q_m = a_m*sqrt(1 - e^2)*sin(E_rad);
            
            %             dMdt_rad_per_s = n_rad_per_s;
            %             dEdt_rad_per_s = dMdt_rad_per_s/(1 - e*cos(E_rad));
            %             dpdt_m_per_s = -a_m*sin(E_rad)*dEdt_rad_per_s;
            %             dqdt_m_per_s = a_m*cos(E_rad)*dEdt_rad_per_s*sqrt(1 - e^2);
            E_deg_epoch = rad2deg(E_rad);
            
            %% *Rotate To ECI*
            Rz_Omega = [ ...
                [cosd(OrbitalE.RA) sind(OrbitalE.RA) 0]; ...
                [-sind(OrbitalE.RA) cosd(OrbitalE.RA) 0]; ...
                [0 0 1]];
            Rx_i = [ ...
                [1 0 0]; ...
                [0 cosd(OrbitalE.Inclination) sind(OrbitalE.Inclination)]; ...
                [0 -sind(OrbitalE.Inclination) cosd(OrbitalE.Inclination)]];
            Rz_omega = [ ...
                [cosd(OrbitalE.AP) sind(OrbitalE.AP) 0]; ...
                [-sind(OrbitalE.AP) cosd(OrbitalE.AP) 0]; ...
                [0 0 1]];
            
            % time of epoch
            [Year,Month,Day,H,M,S] = datevec(OrbitalE.Epoch);
            %             HourUTC = H + M/60.0 + S/3600.0;
            %             jd = juliandate(Year,Month,Day,HourUTC,0,0);
            %             jd0 = juliandate(Year,Month,Day,0,0,0);
            % form time in Julian centuries from J2000
            %             T = (jd - 2451545.0d0)./36525.0d0;
            
            
            %             Theta_deg = 100.460618375 + 36000.770053608336*T + 0.0003879333*T^2 + 15*H + M/4 + mod(S/240,360);
            
            %             Rz_hour = [ ...
            %                 [cosd(Theta_deg) sind(Theta_deg) 0]; ...
            %                 [-sind(Theta_deg) cosd(Theta_deg) 0]; ...
            %                 [0 0 1]];
            
            % position of satellite at epoch in the orbit pq plane
            r_pq = [p_m q_m 0]';
            % position of satellite at epoch in ECI coordinates
            
            %             omega_deg = OrbitalE.AP;
            %             Omega_deg = OrbitalE.AP;
            i_deg = OrbitalE.Inclination;
            
            r_ECI = inv(Rz_Omega)*inv(Rx_i)*inv(Rz_omega)*r_pq;
            r_LLA = eci2lla(r_ECI',datevec(datenum(Year, Month, Day, H, M, S)),'IAU-2000/2006');
            
            
            if app.CreateDataPacketsButton.Enable=="off"
                PassoverTime=app.PassoverTimesEditField.Value;
                GPSRate=app.GPSClockMHzEditField.Value*1E6;
                SDRRate=app.SDRDataRateMHzEditField.Value*1E6;
                %                 Evals = 0:1:(360.0*PassoverTime/(OrbitalE.T*60)); % [deg] values of the eccentric anomaly around orbit
                Evals=linspace(0,(360.0*PassoverTime/(OrbitalE.T*60)),GPSRate*PassoverTime);
            else
                Evals = 0:1:360.0; % [deg] values of the eccentric anomaly around orbit
            end


            Orbit_p = a_m*(cosd(Evals)-e); % [m] orbit positions
            Orbit_q = a_m*sqrt(1 - e^2)*sind(Evals); % [m] orbit positions
            deltaT_s = ((Evals-E_deg_epoch) - e*sind(Evals-E_deg_epoch))/n_deg_per_s; % [s] time since epoch along orbit
            %
            Orbit_ECI = zeros(numel(deltaT_s),3);
            Orbit_LLA = zeros(numel(deltaT_s),3);
            for ipt = 1:size(Orbit_ECI,1)
                r_pq = [Orbit_p(ipt) Orbit_q(ipt) 0]';
                Orbit_ECI(ipt,:) = (inv(Rz_Omega)*inv(Rx_i)*inv(Rz_omega)*r_pq)'; %[Rz_Omega*Rx_i*Rz_omega*r_pq]';
                lla = eci2lla(Orbit_ECI(ipt,:),datevec(datenum(Year, Month, Day, H, M, S+deltaT_s(ipt))),'IAU-2000/2006');
                Orbit_LLA(ipt,:) = lla;
            end
            
            Orbit_ECI=Orbit_ECI/1000;
            Orbit_LLA=Orbit_LLA/1000;
            r_LLA=r_LLA/1000;
        end
        
        function PlotGroundTrack(app,OrbitalE)
            [~,Orbit_LLA,r_LLA] = Elements2Cartesian(app,OrbitalE);
            Orbit_LLA=Orbit_LLA*1000;
            r_LLA=r_LLA*1000;
            
            %                         [Orbit_LLA
            %                             ,r_LLA]=[Orbit_LLA,r_LLA]*1000;
            %% *Plot Cartesian Coordinates*
            %             figure('color','white');
            %             plot3(app.DViewerPlot,Orbit_ECI(:,1),Orbit_ECI(:,2),Orbit_ECI(:,3))
            
            %             xlabel('ECI x [m]');
            %             ylabel('ECI y [m]');
            %             zlabel('ECI z [m]');
            %             title('Satellite Orbit in ECI Coordinates');
            %             grid on
            
            %% *Plot Map*
            %             figure('color','white');
            earth = imread([pwd, '\appResources\groundTrack.jpg']);
            lv= size(earth,1);
            lh= size(earth,2);
            lats =  (1:lv)*180/lv - 90;
            lons =  (1:lh)*360/lh - 180;
            image(app.groundtrackPlot,lons, -lats, earth)
            hold(app.groundtrackPlot, 'on')
            set(app.groundtrackPlot,'ydir','normal');
            grid(app.groundtrackPlot, 'on')
            plot(app.groundtrackPlot,Orbit_LLA(:,2),Orbit_LLA(:,1),'.r');
            plot(app.groundtrackPlot,r_LLA(2),r_LLA(1),'p','MarkerFaceColor',[1 0 0.7],'MarkerEdgeColor','none','Markersize',14);
            set(app.groundtrackPlot,'XTick',(-180:30:180));
            set(app.groundtrackPlot,'YTick',(-90:30:90));
            set(app.groundtrackPlot,'Xcolor',0.3*ones(1,3));
            set(app.groundtrackPlot,'Ycolor',0.3*ones(1,3));
            title(app.groundtrackPlot,['Ground Track for Epoch ' datestr(OrbitalE.Epoch)])
            xlabel(app.groundtrackPlot,'Longitude (degrees)')
            ylabel(app.groundtrackPlot,'Latitude (degrees)')
            axis(app.groundtrackPlot, 'equal')
            axis(app.groundtrackPlot, 'tight')
            set(app.groundtrackPlot,'fontweight','bold');
        end
        
        function resultIND = Search(app)
            
            Namefrag=app.SearchSatelliteEditField.Value;
            SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
            
            charSatNum=sum(char(SatName)~=' ',2); % Number of characters in each satellite name
            Match=ismember(char(SatName),Namefrag);
            
            weirdIn=(sum(abs(diff(Match,2)),2));
            
            
            charSatMatch=sum(Match,2); % Number of matching characters
            nearMatch=zeros(length(Match),1);
            for i=1:length(Match(:,1))
                nearMatch(i)=sum(diff(find(Match(i,:))))/charSatMatch(i);
            end
            
            nearMatch(nearMatch==0)=nan;
            nearMatch(isnan(nearMatch))=999;
            nearMatch=nearMatch./charSatMatch;
            [~,aIndex]=sort(nearMatch);
            resultIND=SatName(aIndex);
            
            
        end
        
        function UpdateSearchSatelliteField(app)
            resultIND = Search(app);
            %             SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
            app.ListBox.Items=cellstr(resultIND);
        end
        
        
        function ErrorMSG(app,ErrorNum)
            if ErrorNum==1
                uialert(app.TLEMaster,'Current directory does not contain the current app executable, please change working directory','Invalid Directory location','Icon','warning');
            elseif ErrorNum==2
                uialert(app.TLEMaster,'Please Choose an Appropriate File','Invalid Node Choice','Icon','warning');
            elseif ErrorNum==3
                uialert(app.TLEMaster,'Either a File has yet to be chosen or the desired file has been moved','Invalid File Location','Icon','warning');
            elseif ErrorNum==3
                uialert(app.TLEMaster,'Unsupported file type chose','Invalid File Type','Icon','warning');
            else
                uialert(app.TLEMaster,'Error message not found','Invalid Error Code','Icon','error');
            end
            
        end
        
        function data = IntroduceError(app, data, TDoAError)
            
            [L,~] = size(data.r_vec);                                       % length of time series of position vectors
            
            % can play around with the distributions in `errorCloud.m`
            v = randn(L, 3);                                                % normal distribution of coordinates
            v = bsxfun(@rdivide, v, sqrt(sum(v.^2, 2)));                    % uniform distribution of directions
            if (app.UniformButton.Value == 1)
                err_mag = TDoAError * rand(L, 1);                           % uniform error vector magnitude distribution
            elseif (app.NormalButton.Value == 1)
                err_mag = TDoAError * randn(L, 1);                          % normal error vector magnitude distribution (std = TDoAError)
            end
            PosOffset = err_mag .* v;                                       % scaling each direction by a randomly distributed length (err_mag)
            
            data.r_vec = data.r_vec + PosOffset;                            % add offsets to each position vector
            data.r_mag = vecnorm(data.r_vec')';                              % find new position veector magnitudes
        end
        
        function UpdateFolderList(app)
            a = app.Tree.Children;
            a.delete;
            folders=dir([pwd '\TDOA Data']);
            folders=string({folders.name});
            folders=folders(3:end);
            for i=1:length(folders)
                uitreenode(app.Tree,'Text',folders(i));
                Sfolders=dir([pwd, '\TDoA Data\',char(folders(i))]);
                Sfolders=string({Sfolders.name});
                Sfolders=Sfolders(3:end);
                for j=1:length(Sfolders)
                    uitreenode(app.Tree.Children(i),'Text',Sfolders(j));
                    
                end
            end
            expand(app.Tree)
        end
        
        function data = LoadData(app)
            data=[];
            data.break=0;
            fileAdd=app.FileLocationAddress.Value;
            if ~isempty(dir(fileAdd))
                %                 data=[];
                filename=dir(fileAdd).name;
                fileType=string(filename(find(filename=='.'):end));
                
                if strcmp(fileType,".txt")
                    fid = fopen(fileAdd);
                    
                    line = fgetl(fid);
                    i = 0;
                    j = 0;
                    k = 0;
                    data.TLE = [];
                    
                    while ischar(line)
                        
                        if (30<sum(line==' ')) && (sum(line==' ')<94) && (length(line)>160) && (sum(line=='-')<10) && (app.TLEincludedinDatafileCheckBox.Value==1) && ~(line(7) == 'E')
                            % TLE header stuff
                            k = k + 1;
                            Line = split(line);
                            t = [char(Line(1)),char(Line(2)),char(Line(3)), char(Line(4))];
                            TLE.n(k) = str2double(Line(5)) * 3600 * 24 / 360; % mean motion [deg / s]
                            TLE.e(k) = str2double(Line(6)); % eccentricity
                            TLE.i(k) = str2double(Line(7)); % inclination [deg]
                            TLE.omega(k) = str2double(Line(8)); % argument of perigee [deg]
                            TLE.Omega(k) = str2double(Line(9)); % RAAN [deg]
                            TLE.M(k) = str2double(Line(10)); % mean anomaly [deg]
                            TLE.Time(k,:) = datetime(t, 'InputFormat', 'ddMMMyyyyHH:mm:ss.SSS');
                            
                            
                            data.TLE = TLE;
                            
                        elseif (sum(line==' ') < 54) && (length(line) > 60) && (sum(line=='-') < 10) && (length(split(line)) == 12)
                            if (length(line) > 150)
                                j = j + 1;
                                Line = split(line);
                                
                                % time stuff
                                t = [char(Line(1)), char(Line(2)), char(Line(3)), char(Line(4))];
                                
                                % position stuff
                                x = str2double(Line(5));
                                y = str2double(Line(6));
                                z = str2double(Line(7));
                                r_vec = [x, y, z];
                                r_mag = str2double(Line(8));
                                
                                % velocity stuff
                                v_x = str2double(Line(9));
                                v_y = str2double(Line(10));
                                v_z = str2double(Line(11));
                                v_vec = [v_x, v_y, v_z];
                                v_mag = str2double(Line(12));
                                
                                % append time and space stuff to data
                                if length(t)<19
                                    data.Time(j,:) = datetime(t, 'InputFormat', 'ddMMMyyyyHH:mm:ss');
                                else
                                    data.Time(j,:) = datetime(t, 'InputFormat', 'ddMMMyyyyHH:mm:ss.SSS');
                                end
                                data.r_vec(j,:) = r_vec;
                                data.r_mag(j) = r_mag';
                                data.v_vec(j,:) = v_vec;
                                data.v_mag(j) = v_mag';
                            else
                                j = j + 1;
                                Line = split(line);
                                
                                % time stuff
                                %                             t = [char(Line(1)),' ', char(Line(2)),' ', char(Line(3)),' ', char(Line(4))];
                                t = [char(Line(1)), char(Line(2)), char(Line(3)), char(Line(4))];
                                
                                % space stuff
                                x = str2double(Line(5));
                                y = str2double(Line(6));
                                z = str2double(Line(7));
                                r_vec = [x, y, z];
                                r_mag = str2double(Line(8));
                                
                                % append time and space stuff to data
                                if length(t)<19
                                    data.Time(j,:) = datetime(t, 'InputFormat', 'ddMMMyyyyHH:mm:ss');
                                else
                                    data.Time(j,:) = datetime(t, 'InputFormat', 'ddMMMyyyyHH:mm:ss.SSS');
                                end
                                data.r_vec(j,:) = r_vec;
                                data.r_mag(j) = r_mag';
                            end
                            
                        end
                        i = i + 1;
                        line = fgetl(fid);
                    end
                    fclose(fid);
                    %                     data.Time=data.Time';
                    data.r_mag=data.r_mag';
                    %                     fid=fopen(fileAdd,'r');
                    %                     tline = fgetl(fid);
                    %                     while ischar(tline)
                    %                         if sum(tline==' ')<40 && length(tline)<40
                    %                         return
                    %
                    %                         end
                    % % disp(tline)
                    % % sum(tline==' ')
                    %                         tline = fgetl(fid)
                    
                    %                     end
                    %                     fclose(fid);
                elseif strcmp(fileType,".csv")
                    warning('off','MATLAB:table:ModifiedAndSavedVarnames'); %Turns off 'cutting off header' warning
                    dataRaw=readtable(fileAdd);
                    
                    if length(char(string(table2array(dataRaw(1,1)))))<19
                        data.Time = datetime( string(table2array(dataRaw(:,1))), 'InputFormat', 'ddMMMyyyyHH:mm:ss');
                    else
                        data.Time = datetime( string(table2array(dataRaw(:,1))), 'InputFormat', 'ddMMMyyyyHH:mm:ss.SSS');
                    end
                    %                      data.Time = datetime( string(table2array(dataRaw(:,1))), 'InputFormat', 'ddMMMyyyyHH:mm:ss.SSS');
                    data.r_vec = table2array(dataRaw(:,2:4));
                    %                             dataRaw(:,2:4);
                    data.r_mag=table2array(dataRaw(:,5));
                    
                    
                    %                     TimeS=char(string(table2array(data(:,1))));
                    %                     TimeS=TimeS(:,12:end);
                    %                     HourT=str2num(TimeS(:,1:2));
                    %                     MinT=str2num(TimeS(:,4:5));
                    %                     SecT=str2num(TimeS(:,7:8));
                    %                     MiliSecT=str2num(TimeS(:,10:end));
                    %                     TimeStamp=[HourT,MinT,SecT,MiliSecT];
                    %                     data=table2array(data(:,2:end));
                    %                     data=[TimeStamp data];
                else
                    ErrorMSG(app, 4); %File type not recognized\
                    data.break=1;
                end


            else
                ErrorMSG(app, 3); %Something happened to the desired file location
                data.break=1;
            end
        end
        
        function WriteData(app,data,dest)
            fileAdd=app.FileLocationAddress.Value;
            if ~isempty(dir(fileAdd))
                %                 data=[];
                filename=dir(fileAdd);
                filename=(filename.name);
                if dest==1
                    FileDest=[pwd,'\TDoA Data\Offset Data\',filename(1:(end-4)),data.name,'.txt'];
                    
                elseif dest==2
                    FileDest=[pwd,'\TDoA Data\Truth Data\',filename(1:(end-4)),'.txt'];
                end
                TMat=char(string(data.Time));
                TMat(TMat=='-')=' ';
                TMat=string(TMat);
                varNames = {'Time (UTCG)','x (km)','y (km)','z (km)','Magnitude (km)'};
                T=table(TMat,data.r_vec(:,1),data.r_vec(:,2),data.r_vec(:,3),data.r_mag(:,1),'VariableNames',varNames);
                writetable(T,FileDest,'Delimiter','tab');
                
                
                %                 fid = fopen( FileDest, 'wt' );
                %
                %                 for i=1:length(data.r_vec(:,1))
                % %                       [a1,a2,a3,a4] = ProcessMyImage( image );
                %                     fprintf( fid, '%s     %.5f   %.5f    %.5f    %.5f\n',TMat,data.r_vec(:,1),data.r_vec(:,2),data.r_vec(:,3),data.r_mag);
                %                 end
                % %                 tabIns=repmat('   ',length(TMat));
                % %                 fprintf( fid, '%s',[char(TMat),tabIns,char(num2str(data.r_vec(:,1))),tabIns,char(num2str(data.r_vec(:,2))),tabIns,char(num2str(data.r_vec(:,3))),tabIns,char(num2str(data.r_mag(:,1)))])
                % %                 fprintf( fid, '%s',[char(TMat),char(num2str(data.r_vec(:,1))),char(num2str(data.r_vec(:,2))),char(num2str(data.r_vec(:,3))),char(num2str(data.r_mag(:,1)))])
                %                 fclose(fid);


                UpdateFolderList(app);
            end
        end
        
        
        function TLEErrorCalc(app)
            
            
            CalcTLE.I=str2num(app.CL2_2.Value);
            CalcTLE.RA=str2num(app.CL2_3.Value);
            CalcTLE.e=str2num(app.CL2_4.Value);
            CalcTLE.AP=str2num(app.CL2_5.Value);
            CalcTLE.MA=str2num(app.CL2_6.Value);
            CalcTLE.MM=str2num(app.CL2_7.Value);
            
            TruthTLE.I=str2num(app.TL2_2.Value);
            TruthTLE.RA=str2num(app.TL2_3.Value);
            TruthTLE.e=str2num(app.TL2_4.Value);
            TruthTLE.AP=str2num(app.TL2_5.Value);
            TruthTLE.MA=str2num(app.TL2_6.Value);
            TruthTLE.MM=str2num(app.TL2_7.Value);
            %             MaxPError=0;
            
            ErrorVec=zeros(6,1);
            if ~isempty(CalcTLE.I) && ~isempty(TruthTLE.I)
                ErrorVec(1,1)=abs((CalcTLE.I-TruthTLE.I)/TruthTLE.I)*100;
                ErrorVec(2,1)=abs((CalcTLE.RA-TruthTLE.RA)/TruthTLE.RA)*100;
                %                 ErrorVec(3,1)=abs((CalcTLE.e-TruthTLE.e)/TruthTLE.e)*100;
                ErrorVec(4,1)=abs((CalcTLE.AP-TruthTLE.AP)/TruthTLE.AP)*100;
                ErrorVec(5,1)=abs((CalcTLE.MA-TruthTLE.MA)/TruthTLE.MA)*100;
                ErrorVec(6,1)=abs((CalcTLE.MM-TruthTLE.MM)/TruthTLE.MM)*100;
                app.RelativeErrorNAMenu.Text=[num2str(ErrorVec(1,1)),'%'];
                app.RelativeErrorNAMenu_2.Text=[num2str(ErrorVec(2,1)),'%'];
                app.RelativeErrorNAMenu_3.Text=[num2str(ErrorVec(3,1)),'%'];
                app.RelativeErrorNAMenu_4.Text=[num2str(ErrorVec(4,1)),'%'];
                app.RelativeErrorNAMenu_5.Text=[num2str(ErrorVec(5,1)),'%'];
                app.RelativeErrorNAMenu_6.Text=[num2str(ErrorVec(6,1)),'%'];
                app.CL2_2.BackgroundColor=[1 1 1];
                app.CL2_3.BackgroundColor=[1 1 1];
                app.CL2_4.BackgroundColor=[1 1 1];
                app.CL2_5.BackgroundColor=[1 1 1];
                app.CL2_6.BackgroundColor=[1 1 1];
                app.CL2_7.BackgroundColor=[1 1 1];
                
                MaxError = (abs(str2num(app.CL1_2.Value) - str2num(app.TL1_2.Value)) / abs(str2num(app.TL1_2.Value))) * 100;
                
%                 MaxError=find(ErrorVec==max(ErrorVec));
%                 eval((['app.CL2_',num2str(MaxError+1),'.BackgroundColor=[1 0 0];']));
%                 app.ErrorEditField.Value=[num2str(ErrorVec(MaxError)),'%'];
                app.ErrorEditField.Value=[num2str(MaxError),'%'];
                %                 app.ErrorEditField.Value = [num2str(sqrt(sum(ErrorVec.^2))), '%'];
%                 MaxPError=ErrorVec(MaxError);
            else
                MaxPError=0;
            end
            %             ErrorVec=max(ErrorVec);
            %             Error.I=abs((CalcTLE.I-TruthTLE.I)/TruthTLE.I)*100;
            %             Error.RA=abs((CalcTLE.RA-TruthTLE.RA)/TruthTLE.RA)*100;
            %             Error.e=abs((CalcTLE.e-TruthTLE.e)/TruthTLE.e)*100;
            %             Error.AP=abs((CalcTLE.AP-TruthTLE.AP)/TruthTLE.AP)*100;
            %             Error.MA=abs((CalcTLE.MA-TruthTLE.MA)/TruthTLE.MA)*100;
            %             Error.MM=abs((CalcTLE.MM-TruthTLE.MM)/TruthTLE.MM)*100;
            
        end
        
        function [data, dataPointsInd] = gibbsDATA(app, data, route)
            % route = 1 if doing single run
            % route = 2 if doing sensitivity analysis
            
            L=length(data.Time);
            if route==1
                center=(app.OffsetfromCenterEditField.Value+50)/100;
                Distribution=app.AVGSpreadEditField.Value/100;
                
            elseif route==2
                center=0.5;
                Distribution=0.5;
            elseif route==3
                center=0.5;
                Distribution=data.Dist;
            else
                return
            end
            centerPoint=ceil(center*L);
            LowPoint = ceil(centerPoint-Distribution*L);
            HighPoint = ceil(centerPoint+Distribution*L);
            if (LowPoint <= 0)
                LowPoint=1;
            end
            if HighPoint>L
                HighPoint=L;
            end
            
            dataPointsInd = [LowPoint; centerPoint; HighPoint];
            data.Time=data.Time(dataPointsInd,:);
            data.r_vec=data.r_vec(dataPointsInd,:);
            data.r_mag=data.r_mag(dataPointsInd,:);
            
        end
        
        function [v_vec, coplanar_val] = gibbs(app, data, mu)
            %   Use Gibb's Method to produce a velocity vector from three consecutive
            %   position vectors.
            %   -----------------------------------------------------------------------
            %
            %   Inputs:
            %   --------
            %   `data` -- structure containing position data that Gibb's method needs:
            %       `r_vec` -- array of three time-consecutive position vectors [km]
            %       `r_mag` -- array of corresponding vector magnitides [km]
            %       `Time`  -- array of datetime objects corresponding to positions
            %   `mu`   -- Standard Gravitational Parameter of the body orbited [km^3 / s^2]
            %
            %   Returns:
            %   --------
            %   'v_vec'        -- velocity vector at the second time [km/s]
            %   'coplanar_val' -- dot product of unit vectors that should be coplanar
            %                       for Gibb's Method to work properly [~] (closer to
            %                       zero is better)
            
            % compute unit vectors (two are unused)
            % C_12 = cross(data.r_vec(1,:), data.r_vec(2,:)) / norm(cross(data.r_vec(1,:), data.r_vec(2,:)));
            C_23 = cross(data.r_vec(2,:), data.r_vec(3,:)) / norm(cross(data.r_vec(2,:), data.r_vec(3,:)));
            % C_31 = cross(data.r_vec(3,:), data.r_vec(1,:)) / norm(cross(data.r_vec(3,:), data.r_vec(1,:)));
            
            % check how coplanar the position vectors are
            coplanar_val = dot((data.r_vec(1,:) / data.r_mag(1)), C_23);
            
            % calculate N, D, and S
            N = data.r_mag(1) * cross(data.r_vec(2,:), data.r_vec(3,:)) + data.r_mag(2) * cross(data.r_vec(3,:), data.r_vec(1,:)) + data.r_mag(3) * cross(data.r_vec(1,:), data.r_vec(2,:));
            D = cross(data.r_vec(1,:), data.r_vec(2,:)) + cross(data.r_vec(2,:), data.r_vec(3,:)) + cross(data.r_vec(3,:), data.r_vec(1,:));
            S = data.r_vec(1,:) * (data.r_mag(2) - data.r_mag(3)) + data.r_vec(2,:) * (data.r_mag(3) - data.r_mag(1)) + data.r_vec(3,:) * (data.r_mag(1) - data.r_mag(2));
            
            % calculate velocity vector at second position vector
            v_vec = sqrt(mu / (norm(N) * norm(D))) * ((cross(D, data.r_vec(2,:)) / data.r_mag(2)) + S);
            
        end
        
        function [i, Omega, e, omega, M, n, theta, h, r_p, r_a] = orbitElements(app, state_vec, mu)
            %   Use a given state vector (x, y, z, xdot, ydot, zdot)' to
            %   compute Keplerian orbital elements.
            %   -----------------------------------------------------------------------
            %
            %   Inputs:
            %   --------
            %   `mu` -- Standard Gravitational Parameter of the body orbited [km^3 / s^2]
            %   `state_vec` -- vector with position and velocity components in the ECEF frame [km] and [km / s]
            %
            %   Returns:
            %   --------
            %   `i`     -- inclination [deg]
            %   `Omega` -- right ascension of the ascending node [deg]
            %   `e`     -- eccentricity [~]
            %   `omega` -- argument of perigee [deg]
            %   `M`     -- mean anomaly [deg]
            %   `n`     -- mean motion [rev / day]
            %   `theta` -- true anomaly [deg]
            %   `h`     -- magnitude of the specific angular momentum [km^2 / s]
            %   `r_p`   -- radius of perigee [km]
            %   `r_a`   -- radius of apogee [km]
            
            % distance
            r = norm(state_vec(1:3));
            
            % speed
            v = norm(state_vec(4:6));
            
            % radial speed
            vr = dot(state_vec(1:3), state_vec(4:6)) / r;
            % if (vr > 0): satellite is flying away from perigee
            % if (vr < 0): satellite is flying toward perigee
            
            % specific angular momentum
            h_vec = cross(state_vec(1:3), state_vec(4:6));
            % magnitude of specific angular momentum
            h = norm(h_vec);
            
            % inclination
            i = acosd(h_vec(3) / h);
            
            % node line vector
            N_vec = cross([0,0,1], h_vec);
            
            % node line magnitude
            N = norm(N_vec);
            
            % right ascension of the ascending node
            if (N_vec(2) >= 0)
                Omega = acosd(N_vec(1) / N);
            else
                Omega = 360 - acosd(N_vec(1) / N);
            end
            
            % eccentricity vector
            e_vec = ((v^2 - (mu / r)) * state_vec(1:3) - r * vr * state_vec(4:6)) / mu;
            % eccentricity
            e = norm(e_vec);
            
            % argument of perigee
            if (e_vec(3) >= 0)
                omega = acosd(dot(N_vec, e_vec) / (N * e));
            else
                omega = 360 - acosd(dot(N_vec, e_vec) / (N * e));
            end
            
            % true anomaly
            if (vr >= 0)
                theta = acosd(dot(e_vec, state_vec(1:3)) / (e * r));
            else
                theta = 360 - acosd(dot(e_vec, state_vec(1:3)) / (e * r));
            end
            
            % eccentric anomaly
            E = 2 * atan(sqrt((1 - e) / (1 + e)) * tand(theta / 2));
            
            % mean anomaly
            M = E - e * sin(E);%[rad]
            M = rad2deg(M);%[deg]
            if (M < 0)
                M = M + 360;
            elseif (M > 360)
                M = M - 360;
            end
            
            % orbital period
            T = 2 * pi * h^3 / (mu^2 * (1 - e^2)^(3/2));%[s]
            
            % mean motion
            n = 2 * pi / T;%[rad / s]
            n = n * 3600 * 24 / (2 * pi);%[rev / day]
            
            % radius of perigee
            r_p = (h^2 / mu) * (1 / (1 + e));
            
            % radius of apogee
            r_a = (h^2 / mu) * (1 / (1 - e));
            
        end
        
        function results = func24(app)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            folderCheck=string({dir(pwd).name});
            if sum(folderCheck=="appResources")<1
                ErrorMSG(app,1)
                return
            else
                UpdateFolderList(app)
            end
            if isfile([pwd '\appResources\RaytheonLogo.png'])
                app.RaytheonLogo.ImageSource=[pwd '\appResources\RaytheonLogo.png'];
                
            end
            if isfile([pwd '\appResources\CULogo.jpg'])
                app.CULogo.ImageSource=[pwd '\appResources\CULogo.jpg'];
            end
            TLEUpdate(app)
            PlotEarth(app)
            UpdateSearchSatelliteField(app)
            %             app.TabGroup4.SelectedTab=app.SensitivityAnalysisTDoAAccuracyTab;
            %             app.TabGroup4.SelectedTab=app.SingleTab;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            TLEUpdate(app)
        end

        % Callback function
        function PlotEarthAxesButtonPushed(app, event)
            PlotEarth(app)
            
        end

        % Callback function
        function PlotCALSPHERE1ButtonPushed(app, event)
            NORADdes="25544";
            TLE = TLEStructExtract(app,NORADdes);
            OrbitalE = TLE2Elements(app, TLE);
            %             Elements = ElementInfo(app,NORADdes);
            plotOrbit(app,OrbitalE)
            
            
        end

        % Value changed function: SElement1
        function SElement1ValueChanged(app, event)
            resultIND = Search(app);
            app.RefinedSearchTable.Data=app.ElementInfoTable.Data(resultIND,:);
            
            app.SearchResultNumTxt.Text=[char(num2str(length(find(resultIND)))) , ' results of ',char(num2str(height(app.ElementInfoTable.Data(:,4)))), ' found'];
            if isempty(app.SElement1.Value)
                UpdateButtonPushed(app);
                
            end
        end

        % Callback function
        function PlotGroundTrackButtonPushed(app, event)
            NORADdes="25544";
            TLE = TLEStructExtract(app,NORADdes);
            OrbitalE = TLE2Elements(app, TLE);
            %             Elements = ElementInfo(app,NORADdes);
            PlotGroundTrack(app,OrbitalE)
            
            
        end

        % Value changed function: SearchSatelliteEditField
        function SearchSatelliteEditFieldValueChanged(app, event)
            UpdateSearchSatelliteField(app)
        end

        % Button pushed function: ConfirmSelectionButton
        function ConfirmSelectionButtonPushed(app, event)
            app.ConfirmedSatelliteEditField.Value=app.ListBox.Value;
            
            
        end

        % Button pushed function: CreateDataPacketsButton
        function CreateDataPacketsButtonPushed(app, event)
            
            SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
            indexVal=strcmp(app.ConfirmedSatelliteEditField.Value,SatName);


            NORADdes=string(table2cell(app.ElementInfoTable.Data(indexVal,2)));
            TLE = TLEStructExtract(app,NORADdes);
            OrbitalE = TLE2Elements(app, TLE);
            %             Elements = ElementInfo(app,NORADdes);
            
            
            [Orbit_ECI,Orbit_LLA,r_LLA]=Elements2Cartesian(app,OrbitalE);
            plotOrbit(app,OrbitalE)


        end

        % Button pushed function: LoadDataFileButton
        function LoadDataFileButtonPushed(app, event)
            %             filename=uigetfile([pwd '\TDOA Data Sets\']);
            %             app.FileLocationAddress.Value=[[pwd '\TDOA Data Sets\' filename]];
            %             data=[];
            if ~isempty(app.Tree.SelectedNodes)
                SelectedF=app.Tree.SelectedNodes.Text;
                
                %             FolderList=strings(length(app.Tree.Children),1)
                for i=1:length(app.Tree.Children)
                    for j=1:length(app.Tree.Children(i).Children)
                        if strcmp(app.Tree.Children(i).Children(j).Text,SelectedF)
                            FolderName=app.Tree.Children(i).Text;
                            FileName=app.Tree.Children(i).Children(j).Text;
                        end
                    end
                end
                if exist('FileName','var')
                    app.FileLocationAddress.Value=[pwd,'\TDoA Data\',FolderName,'\',FileName];
                else
                    ErrorMSG(app, 2); % Alerts that an appropriate file needs to be chosen
                end
            else
                ErrorMSG(app, 2); % Alerts that an appropriate file needs to be chosen
                return
            end


        end

        % Button pushed function: ApplyMonteCarloButton
        function ApplyMonteCarloButtonPushed(app, event)
            %             app.ApplyMonteCarloButton.Enable='off';
            app.ApplyMonteCarloButton.Text='Running...';
            data = LoadData(app);
            if ~isempty(data)
                data = IntroduceError(app, data,app.TDoAErrorkmEditField.Value);
                data.name=app.DataFileNameEditField.Value;
                WriteData(app,data,1)
            end
            app.ApplyMonteCarloButton.Text='Apply Monte Carlo';
            app.ApplyMonteCarloButton.Enable='on';
            uialert(app.TLEMaster,'Data set has been saved as a .txt file','FileWritten','Icon','success');
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            UpdateFolderList(app)
        end

        % Button pushed function: SendasTLEReferenceButton
        function SendasTLEReferenceButtonPushed(app, event)
            SatName=string(table2cell(app.ElementInfoTable.Data(:,1)));
            indexVal=strcmp(app.ConfirmedSatelliteEditField.Value,SatName);


            NORADdes=string(table2cell(app.ElementInfoTable.Data(indexVal,2)));
            TLE = TLEStructExtract(app,NORADdes);
            app.TRef_Name.Value=app.ConfirmedSatelliteEditField.Value;
            app.TL1_1.Value=TLE.L1_1;
            app.TL1_2.Value=TLE.L1_2;
            app.TL1_3.Value=TLE.L1_3;
            app.TL1_4.Value=TLE.L1_4;
            app.TL1_5.Value=TLE.L1_5;
            app.TL1_6.Value=TLE.L1_6;
            app.TL1_7.Value=TLE.L1_7;
            app.TL1_8.Value=TLE.L1_8;
            
            app.TL2_1.Value=TLE.L2_1;
            app.TL2_2.Value=TLE.L2_2;
            app.TL2_3.Value=TLE.L2_3;
            app.TL2_4.Value=TLE.L2_4;
            app.TL2_5.Value=TLE.L2_5;
            app.TL2_6.Value=TLE.L2_6;
            app.TL2_7.Value=TLE.L2_7;


        end

        % Button pushed function: CalcErrorButton
        function CalcErrorButtonPushed(app, event)
            TLEErrorCalc(app)
            
            
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            a1=app.TDoASensitivityPlot;
            f2=figure(1);
            a2=copyobj(a1,f2);
            saveas(f2,'TDoASensitivityAnalysis.png')
            close(f2)
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.CL2_2.Value='';
            app.CL2_3.Value='';
            app.CL2_4.Value='';
            app.CL2_5.Value='';
            app.CL2_6.Value='';
            app.CL2_7.Value='';


            app.RelativeErrorNAMenu.Text='Relative Error: N/A';
            app.RelativeErrorNAMenu_2.Text='Relative Error: N/A';
            app.RelativeErrorNAMenu_3.Text='Relative Error: N/A';
            app.RelativeErrorNAMenu_4.Text='Relative Error: N/A';
            app.RelativeErrorNAMenu_5.Text='Relative Error: N/A';
            app.RelativeErrorNAMenu_6.Text='Relative Error: N/A';
            app.CL2_2.BackgroundColor=[1 1 1];
            app.CL2_3.BackgroundColor=[1 1 1];
            app.CL2_4.BackgroundColor=[1 1 1];
            app.CL2_5.BackgroundColor=[1 1 1];
            app.CL2_6.BackgroundColor=[1 1 1];
            app.CL2_7.BackgroundColor=[1 1 1];
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            
            app.RunButton.Text = 'Running...';
            %             app.RunButton.Enable = 'off';
            data = LoadData(app);
            
            if (~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1) && app.VelocityTimeSeriesincludedinDatafileCheckBox.Value == 1)
                [~, dataPointsInd] = gibbsDATA(app, data, 1);
                state_vec = [data.r_vec(dataPointsInd(2), :), data.v_vec(dataPointsInd(2), :)]';
                mu_E = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                [i, Omega, e, omega, M, n, ~, h, ~, ~] = orbitElements(app, state_vec, mu_E);
                app.TL1_1.Value = ''; app.TL1_1.Enable = 0;
                app.TL1_2.Value = char(string(h)); app.TL1_2.Enable = 1;
                app.TL1_3.Value = ''; app.TL1_3.Enable = 0;
                app.TL1_4.Value = ''; app.TL1_4.Enable = 0;
                app.TL1_5.Value = ''; app.TL1_5.Enable = 0;
                app.TL1_6.Value = ''; app.TL1_6.Enable = 0;
                app.TL1_7.Value = ''; app.TL1_7.Enable = 0;
                app.TL1_8.Value = ''; app.TL1_8.Enable = 0;
                
                app.TL2_1.Value = ''; app.TL2_1.Enable = 0;
                app.TL2_2.Value = char(string(i));
                app.TL2_3.Value = char(string(Omega));
                app.TL2_4.Value = char(string(e));
                app.TL2_5.Value = char(string(omega));
                app.TL2_6.Value = char(string(M));
                app.TL2_7.Value = char(string(n));
                
                app.TRef_Name.Value = char(string(data.Time(dataPointsInd(2))));
                
            elseif (~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1))
                app.TL1_1.Value = '';
                app.TL1_2.Value = '';
                app.TL1_3.Value = '';
                app.TL1_4.Value = '';
                app.TL1_5.Value = '';
                app.TL1_6.Value = '';
                app.TL1_7.Value = '';
                app.TL1_8.Value = '';
                
                app.TL2_1.Value = '';
                app.TL2_2.Value = char(string(data.TLE.i(4)));
                app.TL2_3.Value = char(string(data.TLE.Omega(4)));
                app.TL2_4.Value = char(string(data.TLE.e(4)));
                app.TL2_5.Value = char(string(data.TLE.omega(4)));
                app.TL2_6.Value = char(string(data.TLE.M(4)));
                app.TL2_7.Value = char(string(data.TLE.n(4)));
            end
            
            if (~isempty(data))
                [data, ~] = gibbsDATA(app, data, 1);
                
                % introduce error for now
                app.NormalButton.Value = 1;
                app.UniformButton.Value = 0;
                TDoAError = 100;
                data = IntroduceError(app, data, TDoAError);
                
                
                mu = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                [v_vec, coplanar_val] = gibbs(app, data, mu);
                app.CoplanarValueEditField.Value = num2str(coplanar_val);
                state_vec = [data.r_vec(2,:)'; v_vec']; % state vector
                [i, Omega, e, omega, M, n, ~, h, ~, ~] = orbitElements(app, state_vec, mu);
                
                app.CL1_2.Value = char(string(h)); app.CL1_2.Enable = 1;
                
                app.CL2_2.Value=num2str(i);
                app.CL2_3.Value=num2str(Omega);
                app.CL2_4.Value=num2str(e);
                app.CL2_5.Value=num2str(omega);
                app.CL2_6.Value=num2str(M);
                app.CL2_7.Value=num2str(n);
                uialert(app.TLEMaster,'State vector successfully converted into orbital elements.'...
                    ,'Data Converted','Icon','success');
                TLEErrorCalc(app);
            else
                uialert(app.TLEMaster,'File not valid','File Error','Icon','error');
            end
            
            app.RunButton.Text = 'Run';
            app.RunButton.Enable = 'on';
            
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            
%             app.RunButton.Text = 'Running...';
            %             app.RunButton.Enable = 'off';
            data = LoadData(app);
            if data.break==1
                uialert(app.TLEMaster,'File not valid','File Error','Icon','error');
                return
            end
            
            
            if (~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1) && app.VelocityTimeSeriesincludedinDatafileCheckBox.Value == 1)
                if app.MaximizeButton.Value==1
                    [~, dataPointsInd] = gibbsDATA(app, data, 2);
                elseif  app.SingleSettingsButton.Value==1
                    [~, dataPointsInd] = gibbsDATA(app, data, 1);
                else
                    return
                end
                state_vec = [data.r_vec(dataPointsInd(2), :), data.v_vec(dataPointsInd(2), :)]';
                mu_E = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                [i, Omega, e, omega, M, n, ~, h, ~, ~] = orbitElements(app, state_vec, mu_E);
                app.TL1_1.Value = ''; app.TL1_1.Enable = 0;
                app.TL1_2.Value = char(string(h)); app.TL1_2.Enable = 1;
                app.TL1_3.Value = ''; app.TL1_3.Enable = 0;
                app.TL1_4.Value = ''; app.TL1_4.Enable = 0;
                app.TL1_5.Value = ''; app.TL1_5.Enable = 0;
                app.TL1_6.Value = ''; app.TL1_6.Enable = 0;
                app.TL1_7.Value = ''; app.TL1_7.Enable = 0;
                app.TL1_8.Value = ''; app.TL1_8.Enable = 0;
                
                app.TL2_1.Value = ''; app.TL2_1.Enable = 0;
                app.TL2_2.Value = char(string(i));
                app.TL2_3.Value = char(string(Omega));
                app.TL2_4.Value = char(string(e));
                app.TL2_5.Value = char(string(omega));
                app.TL2_6.Value = char(string(M));
                app.TL2_7.Value = char(string(n));
                
                app.TRef_Name.Value = char(string(data.Time(dataPointsInd(2))));
                
            elseif (~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1))
                app.TL1_1.Value = '';
                app.TL1_2.Value = '';
                app.TL1_3.Value = '';
                app.TL1_4.Value = '';
                app.TL1_5.Value = '';
                app.TL1_6.Value = '';
                app.TL1_7.Value = '';
                app.TL1_8.Value = '';
                
                app.TL2_1.Value = '';
                app.TL2_2.Value = char(string(data.TLE.i(4)));
                app.TL2_3.Value = char(string(data.TLE.Omega(4)));
                app.TL2_4.Value = char(string(data.TLE.e(4)));
                app.TL2_5.Value = char(string(data.TLE.omega(4)));
                app.TL2_6.Value = char(string(data.TLE.M(4)));
                app.TL2_7.Value = char(string(data.TLE.n(4)));
            end


            %             data = IntroduceError(app, data,app.TDoAErrorkmEditField.Value);
            %             TDoAError=app.TDoAErrorkmEditField.Value;
            if ~isempty(data)
                mu = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                
                MaxError = app.ErrorMaxEditField.Value;
                MinError = app.ErrorMinEditField.Value;
                numPoints = app.DataPointsEditField.Value;
                
                ErrorVEC=linspace(MinError, MaxError, numPoints);
                if app.MaximizeButton.Value==1
                    [data, ~] = gibbsDATA(app, data, 2);
                elseif  app.SingleSettingsButton.Value==1
                    [data, ~] = gibbsDATA(app, data, 1);
                else
                    return
                end


                %                 dataCAT = gibbsDATA(app, data, 1);
                ErrorMAT = zeros(length(ErrorVEC), 1);
                for j=1:length(ErrorVEC)
                    [v_vec_T, coplanar_val] = gibbs(app, data, mu);
                    dataCAT = IntroduceError(app, data, ErrorVEC(j));
                    [v_vec, coplanar_val] = gibbs(app, dataCAT, mu);
                    app.CoplanarValueEditField.Value = num2str(coplanar_val);
                    state_vec = [dataCAT.r_vec(2,:)'; v_vec']; % state vector
                    [i, Omega, e, omega, M, n, ~, h, ~, ~] = orbitElements(app, state_vec, mu);
                    
                    app.CL1_2.Value = num2str(h);
                    
                    app.CL2_2.Value=num2str(i);
                    app.CL2_3.Value=num2str(Omega);
                    app.CL2_4.Value=num2str(e);
                    app.CL2_5.Value=num2str(omega);
                    app.CL2_6.Value=num2str(M);
                    app.CL2_7.Value=num2str(n);
                    TLEErrorCalc(app);
                    ErrorVal = str2num(app.ErrorEditField.Value(1:(end-1)));
%                     ErrorVal = norm(v_vec_T - v_vec) / norm(v_vec_T);
                    %                     ErrorMAT(j)=str2num(ErrorVal(1:(end-1)));
                    ErrorMAT(j) = ErrorVal;
                    
                    %                     hold()
                    %                     scatter(app.TDoASensitivityPlot,ErrorVEC(j),ErrorVal)
                    %                     plot(app.TDoASensitivityPlot,[1 2 3 4],'-r');
                    %                     hold(app.TDoASensitivityPlot);
                    %                     plot(app.TDoASensitivityPlot,[10 9 4 7],'--b');
                    
                end
                scatter(app.TDoASensitivityPlot,ErrorVEC,ErrorMAT);
                xlim(app.TDoASensitivityPlot,[0,ErrorVEC(end)]);
                ylim(app.TDoASensitivityPlot,[0,max(ErrorMAT)]);
                
                uialert(app.TLEMaster,'Sensitivity Analysis Completed'...
                    ,'Data Converted','Icon','success');
                %                 TLEErrorCalc(app);
            else
                uialert(app.TLEMaster,'File not valid','File Error','Icon','error');
            end
            
            %             app.RunButton.Text = 'Run';
            %             app.RunButton.Enable = 'on';
            
            
        end

        % Button pushed function: RunAnalysisButton_Pass
        function RunAnalysisButton_PassPushed(app, event)
            %             tion RunAnalysisButtonPushed(app, event)
            
            %             app.RunButton.Text = 'Running...';
            %             app.RunButton.Enable = 'off';
            data = LoadData(app);
            if data.break==1
                uialert(app.TLEMaster,'File not valid','File Error','Icon','error');
                return
            end
            
            
            if ~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1)
                app.TL1_1.Value = '';
                app.TL1_2.Value = '';
                app.TL1_3.Value = '';
                app.TL1_4.Value = '';
                app.TL1_5.Value = '';
                app.TL1_6.Value = '';
                app.TL1_7.Value = '';
                app.TL1_8.Value = '';
                
                app.TL2_1.Value = '';
                app.TL2_2.Value = char(string(data.TLE.i(4)));
                app.TL2_3.Value = char(string(data.TLE.Omega(4)));
                app.TL2_4.Value = char(string(data.TLE.e(4)));
                app.TL2_5.Value = char(string(data.TLE.omega(4)));
                app.TL2_6.Value = char(string(data.TLE.M(4)));
                app.TL2_7.Value = char(string(data.TLE.n(4)));
                
            end
            %             data = IntroduceError(app, data,app.TDoAErrorkmEditField.Value);
            %             TDoAError=app.TDoAErrorkmEditField.Value;
            if ~isempty(data)
                mu = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                
                %                 MaxError=app.ErrorMaxEditField.Value;
                %                 MinError=app.ErrorMinEditField.Value;
                %                 numPoints=app.DataPointsEditField.Value;
                
                
                MinSpread=app.MaxSpreadEditField.Value/100;
                MaxSpread=app.MinSpreadEditField.Value/100;
                numPoints=app.DataPointsEditField_Pass.Value;
                if numPoints==0
                    return
                end
                %                 app.TDoAErrorkmEditField_2.Value
                dataE = IntroduceError(app, data, app.TDoAErrorkmEditField_2.Value);
                PassLengthVEC=linspace(MinSpread,MaxSpread, numPoints);

                ErrorMAT=zeros(length(PassLengthVEC),1);
                TimeMAT=zeros(length(PassLengthVEC),1);
                for j=1:length(PassLengthVEC)
                    [dataCAT, ~] =gibbsDATA(app, data, 2);
                    [v_vec_T, coplanar_val] = gibbs(app, dataCAT, mu);
                    %                     dataCAT = IntroduceError(app, data, ErrorVEC(j));
                    dataE.Dist=PassLengthVEC(j);
                    [dataCAT, ~] =gibbsDATA(app, dataE, 3);
                    TimeMAT(j)=seconds(abs(dataCAT.Time(1)-dataCAT.Time(2)));
                    [v_vec, coplanar_val] = gibbs(app, dataCAT, mu);
                    app.CoplanarValueEditField.Value = num2str(coplanar_val);
                    state_vec = [dataCAT.r_vec(2,:)'; v_vec']; % state vector
                    [i, Omega, e, omega, M, n, ~, ~, ~, ~] = orbitElements(app, state_vec, mu);
                    app.CL2_2.Value=num2str(i);
                    app.CL2_3.Value=num2str(Omega);
                    app.CL2_4.Value=num2str(e);
                    app.CL2_5.Value=num2str(omega);
                    app.CL2_6.Value=num2str(M);
                    app.CL2_7.Value=num2str(n);
                    TLEErrorCalc(app);
                    %                     ErrorVal=app.ErrorEditField.Value;
                    ErrorVal = norm(v_vec_T - v_vec) / norm(v_vec_T);
                    %                     ErrorMAT(j)=str2num(ErrorVal(1:(end-1)));
                    ErrorMAT(j) = ErrorVal;
                    
                    %                     hold()
                    %                     scatter(app.TDoASensitivityPlot,ErrorVEC(j),ErrorVal)
                    %                     plot(app.TDoASensitivityPlot,[1 2 3 4],'-r');
                    %                     hold(app.TDoASensitivityPlot);
                    %                     plot(app.TDoASensitivityPlot,[10 9 4 7],'--b');
                    
                end
                scatter(app.PassoverSensitivityPlot,TimeMAT,ErrorMAT);
                xlim(app.PassoverSensitivityPlot,[0,max(TimeMAT)]);
                ylim(app.PassoverSensitivityPlot,[0,max(ErrorMAT)]);
                
                uialert(app.TLEMaster,'Sensitivity Analysis Completed'...
                    ,'Data Converted','Icon','success');
                %                 TLEErrorCalc(app);
            else
                uialert(app.TLEMaster,'File not valid','File Error','Icon','error');
            end


        end

        % Button pushed function: SaveFigureButton_Pass
        function SaveFigureButton_PassPushed(app, event)
            a1=app.PassoverSensitivityPlot;
            f2=figure(1);
            a2=copyobj(a1,f2);
            saveas(f2,'PassLengthSensitivityAnalysis.png')
            close(f2)
        end

        % Button pushed function: BUTTONButton
        function BUTTONButtonPushed(app, event)
            data = LoadData(app);
            
            if (~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1) && app.VelocityTimeSeriesincludedinDatafileCheckBox.Value == 1)
                [~, dataPointsInd] = gibbsDATA(app, data, 1);
                state_vec = [data.r_vec(dataPointsInd(2), :), data.v_vec(dataPointsInd(2), :)]';
                mu_E = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                [i, Omega, e, omega, M, n, ~, ~, ~, ~] = orbitElements(app, state_vec, mu_E);
                app.TL1_1.Value = ''; app.TL1_1.Enable = 0;
                app.TL1_2.Value = ''; app.TL1_2.Enable = 0;
                app.TL1_3.Value = ''; app.TL1_3.Enable = 0;
                app.TL1_4.Value = ''; app.TL1_4.Enable = 0;
                app.TL1_5.Value = ''; app.TL1_5.Enable = 0;
                app.TL1_6.Value = ''; app.TL1_6.Enable = 0;
                app.TL1_7.Value = ''; app.TL1_7.Enable = 0;
                app.TL1_8.Value = ''; app.TL1_8.Enable = 0;
                
                app.TL2_1.Value = ''; app.TL2_1.Enable = 0;
                app.TL2_2.Value = char(string(i));
                app.TL2_3.Value = char(string(Omega));
                app.TL2_4.Value = char(string(e));
                app.TL2_5.Value = char(string(omega));
                app.TL2_6.Value = char(string(M));
                app.TL2_7.Value = char(string(n));
                
                app.TRef_Name.Value = char(string(data.Time(dataPointsInd(2))));
                
            elseif (~isempty(data.TLE) && (app.TLEincludedinDatafileCheckBox.Value == 1))
                app.TL1_1.Value = '';
                app.TL1_2.Value = '';
                app.TL1_3.Value = '';
                app.TL1_4.Value = '';
                app.TL1_5.Value = '';
                app.TL1_6.Value = '';
                app.TL1_7.Value = '';
                app.TL1_8.Value = '';
                
                app.TL2_1.Value = '';
                app.TL2_2.Value = char(string(data.TLE.i(4)));
                app.TL2_3.Value = char(string(data.TLE.Omega(4)));
                app.TL2_4.Value = char(string(data.TLE.e(4)));
                app.TL2_5.Value = char(string(data.TLE.omega(4)));
                app.TL2_6.Value = char(string(data.TLE.M(4)));
                app.TL2_7.Value = char(string(data.TLE.n(4)));
            end
            
            if (~isempty(data))
%                 [data, ~] = gibbsDATA(app, data, 1);
                
                
                app.NormalButton.Value = 1;
                app.UniformButton.Value = 0;
                TDoAError = 100;
                data = IntroduceError(app, data, TDoAError);
                
                t_sec = seconds(data.Time - data.Time(1));
                
                clear figure(2)
                figure(2)
                plot(t_sec, data.r_vec(:,1))
                hold on
                p = polyfit(t_sec, data.r_vec(:,1), 3);
                data.r_vec(:,1) = polyval(p, t_sec);
                p = polyfit(t_sec, data.r_vec(:,2), 3);
                data.r_vec(:,2) = polyval(p, t_sec);
                p = polyfit(t_sec, data.r_vec(:,3), 3);
                data.r_vec(:,3) = polyval(p, t_sec);
                plot(t_sec, data.r_vec(:,1), "Color", 'r')
                
                [data, ~] = gibbsDATA(app, data, 1);
                
%                 data.r_vec(:,1) = polyval(p, t_sec(dataP) 
                
                mu = 3.986004418e+05;%[km^3 / s^2] standard gravitational parameter of earth
                [v_vec, coplanar_val] = gibbs(app, data, mu);
                app.CoplanarValueEditField.Value = num2str(coplanar_val);
                state_vec = [data.r_vec(2,:)'; v_vec']; % state vector
                [i, Omega, e, omega, M, n, ~, h, ~, ~] = orbitElements(app, state_vec, mu);
                
                app.CL1_2.Value = char(string(h)); app.CL1_2.Enable = 1;
                
                app.CL2_2.Value=num2str(i);
                app.CL2_3.Value=num2str(Omega);
                app.CL2_4.Value=num2str(e);
                app.CL2_5.Value=num2str(omega);
                app.CL2_6.Value=num2str(M);
                app.CL2_7.Value=num2str(n);
                uialert(app.TLEMaster,'State vector successfully converted into orbital elements.'...
                    ,'Data Converted','Icon','success');
                TLEErrorCalc(app);
            else
                uialert(app.TLEMaster,'File not valid','File Error','Icon','error');
            end
                
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TLEMaster = uifigure('Visible', 'off');
            app.TLEMaster.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.TLEMaster);
            app.TruthDataTab = uitab(app.TabGroup);
            app.TruthDataTab.Title = 'Truth Data';
            app.EstimatedDatasizeEditFieldLabel = uilabel(app.TruthDataTab);
            app.EstimatedDatasizeEditFieldLabel.Enable = 'off';
            app.EstimatedDatasizeEditFieldLabel.Text = 'Estimated Data size';
            app.EstimatedDatasizeEditField = uieditfield(app.TruthDataTab, 'text');
            app.EstimatedDatasizeEditField.Editable = 'off';
            app.EstimatedDatasizeEditField.Enable = 'off';
            app.CreateDataPacketsButton = uibutton(app.TruthDataTab, 'push');
            app.CreateDataPacketsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDataPacketsButtonPushed, true);
            app.CreateDataPacketsButton.Text = 'Create Data Packets';
            app.Panel = uipanel(app.TruthDataTab);
            app.Panel.Title = 'Panel';
            app.PassoverTimesEditFieldLabel = uilabel(app.Panel);
            app.PassoverTimesEditFieldLabel.Text = 'PassoverTime(s)';
            app.PassoverTimesEditField = uieditfield(app.Panel, 'numeric');
            app.PassoverTimesEditField.Value = 1;
            app.SDRDataRateMHzEditFieldLabel = uilabel(app.Panel);
            app.SDRDataRateMHzEditFieldLabel.Text = 'SDR Data Rate(MHz)';
            app.SDRDataRateMHzEditField = uieditfield(app.Panel, 'numeric');
            app.GPSClockMHzEditFieldLabel = uilabel(app.Panel);
            app.GPSClockMHzEditFieldLabel.Text = 'GPS Clock(MHz)';
            app.GPSClockMHzEditField = uieditfield(app.Panel, 'numeric');
            app.CULogo = uiimage(app.TruthDataTab);
            app.RaytheonLogo = uiimage(app.TruthDataTab);
            app.ListBox = uilistbox(app.TruthDataTab);
            app.ListBox.Items = {'Item 1', ''};
            app.SearchSatelliteEditFieldLabel = uilabel(app.TruthDataTab);
            app.SearchSatelliteEditFieldLabel.Text = 'Search Satellite';
            app.SearchSatelliteEditField = uieditfield(app.TruthDataTab, 'text');
            app.SearchSatelliteEditField.ValueChangedFcn = createCallbackFcn(app, @SearchSatelliteEditFieldValueChanged, true);
            app.SearchSatelliteEditField.Value = 'CSIM';
            app.ConfirmedSatelliteEditFieldLabel = uilabel(app.TruthDataTab);
            app.ConfirmedSatelliteEditFieldLabel.Text = 'Confirmed Satellite';
            app.ConfirmedSatelliteEditField = uieditfield(app.TruthDataTab, 'text');
            app.ConfirmSelectionButton = uibutton(app.TruthDataTab, 'push');
            app.ConfirmSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmSelectionButtonPushed, true);
            app.ConfirmSelectionButton.Text = 'Confirm Selection';
            app.V17Label = uilabel(app.TruthDataTab);
            app.V17Label.Enable = 'off';
            app.V17Label.Text = 'V 1.7';
            app.SendasTLEReferenceButton = uibutton(app.TruthDataTab, 'push');
            app.SendasTLEReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SendasTLEReferenceButtonPushed, true);
            app.SendasTLEReferenceButton.Text = 'Send as TLE Reference';
            app.TLECalcTab = uitab(app.TabGroup);
            app.TLECalcTab.Title = 'TLE Calc';
            app.FileLocationAddress = uieditfield(app.TLECalcTab, 'text');
            app.FileLocationAddress.Editable = 'off';
            app.FileLocationAddress.Enable = 'off';
            app.FilePreparationPanel = uipanel(app.TLECalcTab);
            app.FilePreparationPanel.Title = 'File Preparation';
            app.Tree = uitree(app.FilePreparationPanel);
            app.LoadDataFileButton = uibutton(app.FilePreparationPanel, 'push');
            app.LoadDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFileButtonPushed, true);
            app.LoadDataFileButton.Text = 'Load Data File';
            app.MonteCarloPanel = uipanel(app.FilePreparationPanel);
            app.MonteCarloPanel.Title = 'Monte Carlo';
            app.TDoAErrorkmEditField = uieditfield(app.MonteCarloPanel, 'numeric');
            app.TDoAErrorkmEditFieldLabel = uilabel(app.MonteCarloPanel);
            app.TDoAErrorkmEditFieldLabel.Text = 'TDoA Error[km]';
            app.ApplyMonteCarloButton = uibutton(app.MonteCarloPanel, 'push');
            app.ApplyMonteCarloButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMonteCarloButtonPushed, true);
            app.ApplyMonteCarloButton.Text = 'Apply Monte Carlo';
            app.DistributionButtonGroup = uibuttongroup(app.MonteCarloPanel);
            app.DistributionButtonGroup.Title = 'Distribution';
            app.NormalButton = uiradiobutton(app.DistributionButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.UniformButton = uiradiobutton(app.DistributionButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.DataFileNameEditFieldLabel = uilabel(app.MonteCarloPanel);
            app.DataFileNameEditFieldLabel.Text = 'DataFile Name';
            app.DataFileNameEditField = uieditfield(app.MonteCarloPanel, 'text');
            app.DataRestrictionsPanel = uipanel(app.FilePreparationPanel);
            app.DataRestrictionsPanel.Title = 'Data Restrictions';
            app.ApplyRestrictionsButton = uibutton(app.DataRestrictionsPanel, 'push');
            app.ApplyRestrictionsButton.Text = 'Apply Restrictions';
            app.PassoverLengthsLabel = uilabel(app.DataRestrictionsPanel);
            app.PassoverLengthsLabel.Text = 'Passover Length(s):';
            app.PassoverLengthsEditField = uieditfield(app.DataRestrictionsPanel, 'numeric');
            app.PassoverLengthsEditField.Value = 1;
            app.SDRDataRateMHzLabel = uilabel(app.DataRestrictionsPanel);
            app.SDRDataRateMHzLabel.Text = 'SDR Data Rate(MHz):';
            app.SDRDataRateMHzEditField_2 = uieditfield(app.DataRestrictionsPanel, 'numeric');
            app.GPSClockMHzLabel = uilabel(app.DataRestrictionsPanel);
            app.GPSClockMHzLabel.Text = 'GPS Clock(MHz):';
            app.GPSClockMHzEditField_2 = uieditfield(app.DataRestrictionsPanel, 'numeric');
            app.PingRateLabel = uilabel(app.DataRestrictionsPanel);
            app.PingRateLabel.Text = 'Ping Rate:';
            app.PingRateEditField = uieditfield(app.DataRestrictionsPanel, 'numeric');
            app.Button = uibutton(app.FilePreparationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.TLEComparisonPanel = uipanel(app.TLECalcTab);
            app.TLEComparisonPanel.Title = 'TLE Comparison';
            app.TabGroup4 = uitabgroup(app.TLEComparisonPanel);
            app.SingleTab = uitab(app.TabGroup4);
            app.SingleTab.Title = 'Single';
            app.AVGSpreadEditFieldLabel = uilabel(app.SingleTab);
            app.AVGSpreadEditFieldLabel.Text = 'AVG % Spread';
            app.AVGSpreadEditField = uieditfield(app.SingleTab, 'numeric');
            app.AVGSpreadEditField.Value = 100;
            app.OffsetfromCenterEditFieldLabel = uilabel(app.SingleTab);
            app.OffsetfromCenterEditFieldLabel.Text = '% Offset from Center';
            app.OffsetfromCenterEditField = uieditfield(app.SingleTab, 'numeric');
            app.RunButton = uibutton(app.SingleTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CoplanarValueEditFieldLabel = uilabel(app.SingleTab);
            app.CoplanarValueEditFieldLabel.Text = 'Coplanar Value';
            app.CoplanarValueEditField = uieditfield(app.SingleTab, 'text');
            app.CoplanarValueEditField.Editable = 'off';
            app.accuratewhenclosetozeroLabel = uilabel(app.SingleTab);
            app.accuratewhenclosetozeroLabel.Text = '(accurate when close to zero)';
            app.TLEincludedinDatafileCheckBox = uicheckbox(app.SingleTab);
            app.TLEincludedinDatafileCheckBox.Text = 'TLE included in Data file';
            app.TLEincludedinDatafileCheckBox.Value = true;
            app.VelocityTimeSeriesincludedinDatafileCheckBox = uicheckbox(app.SingleTab);
            app.VelocityTimeSeriesincludedinDatafileCheckBox.Text = 'Velocity Time Series included in Data file';
            app.SensitivityAnalysisTDoAAccuracyTab = uitab(app.TabGroup4);
            app.SensitivityAnalysisTDoAAccuracyTab.Title = 'Sensitivity Analysis(TDoA Accuracy)';
            app.SaveFigureButton = uibutton(app.SensitivityAnalysisTDoAAccuracyTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.PassoverLengthButtonGroup = uibuttongroup(app.SensitivityAnalysisTDoAAccuracyTab);
            app.PassoverLengthButtonGroup.Title = 'Passover Length';
            app.MaximizeButton = uiradiobutton(app.PassoverLengthButtonGroup);
            app.MaximizeButton.Text = 'Maximize';
            app.MaximizeButton.Value = true;
            app.SingleSettingsButton = uiradiobutton(app.PassoverLengthButtonGroup);
            app.SingleSettingsButton.Text = 'Single Settings';
            app.TDoAAccSettingsPanel = uipanel(app.SensitivityAnalysisTDoAAccuracyTab);
            app.TDoAAccSettingsPanel.Title = 'TDoA Acc. Settings';
            app.DataPointsEditFieldLabel = uilabel(app.TDoAAccSettingsPanel);
            app.DataPointsEditFieldLabel.Text = 'Data Points';
            app.DataPointsEditField = uieditfield(app.TDoAAccSettingsPanel, 'numeric');
            app.ErrorMinEditFieldLabel = uilabel(app.TDoAAccSettingsPanel);
            app.ErrorMinEditFieldLabel.Text = 'Error Min:';
            app.ErrorMinEditField = uieditfield(app.TDoAAccSettingsPanel, 'numeric');
            app.ErrorMaxEditFieldLabel = uilabel(app.TDoAAccSettingsPanel);
            app.ErrorMaxEditFieldLabel.Text = 'Error Max:';
            app.ErrorMaxEditField = uieditfield(app.TDoAAccSettingsPanel, 'numeric');
            app.RunAnalysisButton = uibutton(app.TDoAAccSettingsPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.TDoASensitivityPlot = uiaxes(app.SensitivityAnalysisTDoAAccuracyTab);
            title(app.TDoASensitivityPlot, 'TDoA Accuracy Sensitivity Analysis')
            xlabel(app.TDoASensitivityPlot, 'TDoA Error(km)')
            ylabel(app.TDoASensitivityPlot, '% TLE Difference')
            zlabel(app.TDoASensitivityPlot, 'Z')
            app.SensitivityAnalysisPassLengthTab = uitab(app.TabGroup4);
            app.SensitivityAnalysisPassLengthTab.Title = 'Sensitivity Analysis(Pass Length)';
            app.SaveFigureButton_Pass = uibutton(app.SensitivityAnalysisPassLengthTab, 'push');
            app.SaveFigureButton_Pass.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButton_PassPushed, true);
            app.SaveFigureButton_Pass.Text = 'Save Figure';
            app.PassLengthSettingsPanel = uipanel(app.SensitivityAnalysisPassLengthTab);
            app.PassLengthSettingsPanel.Title = 'Pass Length Settings:';
            app.DataPointsEditField_2Label = uilabel(app.PassLengthSettingsPanel);
            app.DataPointsEditField_2Label.Text = 'Data Points';
            app.DataPointsEditField_Pass = uieditfield(app.PassLengthSettingsPanel, 'numeric');
            app.DataPointsEditField_Pass.Value = 100;
            app.MinSpreadEditFieldLabel = uilabel(app.PassLengthSettingsPanel);
            app.MinSpreadEditFieldLabel.Text = 'Min % Spread:';
            app.MinSpreadEditField = uieditfield(app.PassLengthSettingsPanel, 'numeric');
            app.MinSpreadEditField.Value = 0.3;
            app.MaxSpreadEditFieldLabel = uilabel(app.PassLengthSettingsPanel);
            app.MaxSpreadEditFieldLabel.Text = 'Max % Spread:';
            app.MaxSpreadEditField = uieditfield(app.PassLengthSettingsPanel, 'numeric');
            app.MaxSpreadEditField.Value = 50;
            app.RunAnalysisButton_Pass = uibutton(app.PassLengthSettingsPanel, 'push');
            app.RunAnalysisButton_Pass.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_PassPushed, true);
            app.RunAnalysisButton_Pass.Text = 'Run Analysis';
            app.TDoASettingsPanel = uipanel(app.SensitivityAnalysisPassLengthTab);
            app.TDoASettingsPanel.Title = 'TDoA Settings';
            app.TDoAErrorkmEditField_2Label = uilabel(app.TDoASettingsPanel);
            app.TDoAErrorkmEditField_2Label.Text = 'TDoA Error(km):';
            app.TDoAErrorkmEditField_2 = uieditfield(app.TDoASettingsPanel, 'numeric');
            app.PassoverSensitivityPlot = uiaxes(app.SensitivityAnalysisPassLengthTab);
            title(app.PassoverSensitivityPlot, 'Passover Length Sensitivity Analysis')
            xlabel(app.PassoverSensitivityPlot, 'Passover Length(s)')
            ylabel(app.PassoverSensitivityPlot, '% TLE Difference')
            zlabel(app.PassoverSensitivityPlot, 'Z')
            app.TruthTLEPanel = uipanel(app.TLEComparisonPanel);
            app.TruthTLEPanel.Title = 'Truth TLE';
            app.TRef_Name = uieditfield(app.TruthTLEPanel, 'text');
            app.TRef_Name.Editable = 'off';
            app.TL1_1 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_1.Editable = 'off';
            app.TL1_2 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_2.Editable = 'off';
            app.TL1_3 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_3.Editable = 'off';
            app.TL1_4 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_4.Editable = 'off';
            app.TL1_5 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_5.Editable = 'off';
            app.TL1_6 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_6.Editable = 'off';
            app.TL1_7 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_7.Editable = 'off';
            app.TL1_8 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL1_8.Editable = 'off';
            app.TL2_1 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_1.Editable = 'off';
            app.TL2_2 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_2.Editable = 'off';
            app.TL2_3 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_3.Editable = 'off';
            app.TL2_4 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_4.Editable = 'off';
            app.TL2_5 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_5.Editable = 'off';
            app.TL2_6 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_6.Editable = 'off';
            app.TL2_7 = uieditfield(app.TruthTLEPanel, 'text');
            app.TL2_7.Editable = 'off';
            app.ErrorEditFieldLabel = uilabel(app.TruthTLEPanel);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorEditField = uieditfield(app.TruthTLEPanel, 'text');
            app.ErrorEditField.Editable = 'off';
            app.CalculatedTLEPanel = uipanel(app.TLEComparisonPanel);
            app.CalculatedTLEPanel.Title = 'Calculated TLE';
            app.CL1_1 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_1.Editable = 'off';
            app.CL1_1.Enable = 'off';
            app.CL1_2 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_2.Editable = 'off';
            app.CL1_2.Enable = 'off';
            app.CL2_1 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_1.Editable = 'off';
            app.CL2_1.Enable = 'off';
            app.CRef_Name = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CRef_Name.Editable = 'off';
            app.CRef_Name.Enable = 'off';
            app.CL2_2 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_2.Editable = 'off';
            app.CL2_2.Value = '51.6416';
            app.CL1_3 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_3.Editable = 'off';
            app.CL1_3.Enable = 'off';
            app.CL2_3 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_3.Editable = 'off';
            app.CL2_3.Value = '247.4627';
            app.CL1_4 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_4.Editable = 'off';
            app.CL1_4.Enable = 'off';
            app.CL2_4 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_4.Editable = 'off';
            app.CL2_4.Value = '0.0006703';
            app.CL1_5 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_5.Editable = 'off';
            app.CL1_5.Enable = 'off';
            app.CL2_5 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_5.Editable = 'off';
            app.CL2_5.Value = '130.5360';
            app.CL1_6 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_6.Editable = 'off';
            app.CL1_6.Enable = 'off';
            app.CL2_6 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_6.Editable = 'off';
            app.CL2_6.Value = '325.0288';
            app.CL1_7 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_7.Editable = 'off';
            app.CL1_7.Enable = 'off';
            app.CL2_7 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL2_7.Editable = 'off';
            app.CL2_7.Value = '15.72125391';
            app.CL1_8 = uieditfield(app.CalculatedTLEPanel, 'text');
            app.CL1_8.Editable = 'off';
            app.CL1_8.Enable = 'off';
            app.CalcErrorButton = uibutton(app.CalculatedTLEPanel, 'push');
            app.CalcErrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalcErrorButtonPushed, true);
            app.CalcErrorButton.Text = 'Calc Error';
            app.Reset = uibutton(app.CalculatedTLEPanel, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = '';
            app.BUTTONButton = uibutton(app.CalculatedTLEPanel, 'push');
            app.BUTTONButton.ButtonPushedFcn = createCallbackFcn(app, @BUTTONButtonPushed, true);
            app.BUTTONButton.Text = 'BUTTON';
            app.FileNameEditField = uieditfield(app.TLECalcTab, 'text');
            app.FileNameEditField.Editable = 'off';
            app.FileNameEditField.Enable = 'off';
            app.TLETab = uitab(app.TabGroup);
            app.TLETab.Title = 'TLE';
            app.UpdateButton = uibutton(app.TLETab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TLEfilename = uieditfield(app.TLETab, 'text');
            app.TLEfilename.Editable = 'off';
            app.TLEfilename.Enable = 'off';
            app.TLEfilename.Value = 'SATINFO_TLE.txt';
            app.DatafromEditFieldLabel = uilabel(app.TLETab);
            app.DatafromEditFieldLabel.Enable = 'off';
            app.DatafromEditFieldLabel.Text = 'Data from: ';
            app.DatafromEditField = uieditfield(app.TLETab, 'text');
            app.DatafromEditField.Editable = 'off';
            app.DatafromEditField.Enable = 'off';
            app.DatafromEditField.Value = 'https://www.celestrak.com/NORAD/elements/active.txt';
            app.NetworkConnectionLabel = uilabel(app.TLETab);
            app.NetworkConnectionLabel.Enable = 'off';
            app.NetworkConnectionLabel.Text = 'Network Connection:';
            app.NetworkConnectionLamp = uilamp(app.TLETab);
            app.GPSCoordLabel = uilabel(app.TLETab);
            app.GPSCoordLabel.Text = 'GPS Coord:';
            app.GPSCoordEditField = uieditfield(app.TLETab, 'text');
            app.GPSCoordEditField.Value = '40.0510522,-105.2290141';
            app.TableTab = uitabgroup(app.TLETab);
            app.OrbitalElementsTab = uitab(app.TableTab);
            app.OrbitalElementsTab.Title = 'Orbital Elements';
            app.ElementInfoTable = uitable(app.OrbitalElementsTab);
            app.ElementInfoTable.ColumnName = {'Name'; 'NORAD'; 'Int. D'; 'a[km]'; 'Period[s]'; 'Inc.[deg]'; 'Eccentricity'; 'Right Ascension[deg]'; 'Arg. of Perigee[deg]'; 'Mean Anomaly'};
            app.ElementInfoTable.RowName = {};
            app.TLE_tab = uitab(app.TableTab);
            app.TLE_tab.Title = 'TLE';
            app.TLEInfoTable = uitable(app.TLE_tab);
            app.TLEInfoTable.ColumnName = {'Name'; '1-1'; '1-2'; '1-3'; '1-4'; '1-5'; '1-6'; '1-7'; '1-8'; '2-1'; '2-2'; '2-3'; '2-4'; '2-5'; '2-6'; '2-7'};
            app.TLEInfoTable.RowName = {};
            app.SearchTab = uitab(app.TableTab);
            app.SearchTab.Title = 'Search';
            app.SearchElement1Label = uilabel(app.SearchTab);
            app.SearchElement1Label.Text = 'Search Element 1';
            app.SElement1 = uieditfield(app.SearchTab, 'text');
            app.SElement1.ValueChangedFcn = createCallbackFcn(app, @SElement1ValueChanged, true);
            app.SearchElement2EditFieldLabel = uilabel(app.SearchTab);
            app.SearchElement2EditFieldLabel.Text = 'Search Element 2';
            app.SElement2 = uieditfield(app.SearchTab, 'text');
            app.RefinedSearchTable = uitable(app.SearchTab);
            app.RefinedSearchTable.ColumnName = {'Name'; 'NORAD'; 'Int. D'; 'a[km]'; 'Period[s]'; 'Inc.[deg]'; 'Eccentricity'; 'Right Ascension[deg]'; 'Arg. of Perigee[deg]'; 'Mean Anomaly'};
            app.RefinedSearchTable.RowName = {};
            app.SearchResultNumTxt = uilabel(app.SearchTab);
            app.SearchResultNumTxt.Enable = 'off';
            app.SearchResultNumTxt.Text = '# of # Results';
            app.TotalResultsTxt = uilabel(app.TLETab);
            app.TotalResultsTxt.Enable = 'off';
            app.TotalResultsTxt.Text = ' # Results';
            app.OrbitViewerTab = uitab(app.TabGroup);
            app.OrbitViewerTab.Title = 'Orbit Viewer';
            app.TabGroup3 = uitabgroup(app.OrbitViewerTab);
            app.DOrbitTab = uitab(app.TabGroup3);
            app.DOrbitTab.Title = '3D Orbit';
            app.DViewerPlot = uiaxes(app.DOrbitTab);
            app.DViewerPlot.XTick = [];
            app.DViewerPlot.YTick = [];
            app.GroundTrackTab_3 = uitab(app.TabGroup3);
            app.GroundTrackTab_3.Title = 'Ground Track';
            app.groundtrackPlot = uiaxes(app.GroundTrackTab_3);
            app.groundtrackPlot.XLim = [0 1];
            app.groundtrackPlot.XTick = [];
            app.groundtrackPlot.XTickLabel = '';
            app.groundtrackPlot.YTick = [];
            app.groundtrackPlot.YTickLabel = '';
            app.DisplayInformationPanel = uipanel(app.OrbitViewerTab);
            app.DisplayInformationPanel.Title = 'Display Information';
            app.CurrentlyDisplayedEditFieldLabel = uilabel(app.DisplayInformationPanel);
            app.CurrentlyDisplayedEditFieldLabel.Text = 'Currently Displayed';
            app.CurrentlyDisplayedEditField = uieditfield(app.DisplayInformationPanel, 'text');
            app.CurrentlyDisplayedEditField.Editable = 'off';
            app.ResetButton = uibutton(app.DisplayInformationPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.CInclination = uicontextmenu(app.TLEMaster);
            % Assign app.CInclination
            app.TL2_2.ContextMenu = app.CInclination;
            app.CL2_2.ContextMenu = app.CInclination;
            app.InclinationdegMenu = uimenu(app.CInclination);
            app.InclinationdegMenu.Text = 'Inclination [deg]';
            app.RelativeErrorNAMenu = uimenu(app.CInclination);
            app.RelativeErrorNAMenu.Text = 'Relative Error: N/A';
            app.CRightAscension = uicontextmenu(app.TLEMaster);
            % Assign app.CRightAscension
            app.TL2_3.ContextMenu = app.CRightAscension;
            app.CL2_3.ContextMenu = app.CRightAscension;
            app.RightAscensiondegMenu = uimenu(app.CRightAscension);
            app.RightAscensiondegMenu.Text = 'Right Ascension [deg]';
            app.RelativeErrorNAMenu_2 = uimenu(app.CRightAscension);
            app.RelativeErrorNAMenu_2.Text = 'Relative Error: N/A';
            app.CEccentricity = uicontextmenu(app.TLEMaster);
            % Assign app.CEccentricity
            app.TL2_4.ContextMenu = app.CEccentricity;
            app.CL2_4.ContextMenu = app.CEccentricity;
            app.EccentricityMenu = uimenu(app.CEccentricity);
            app.EccentricityMenu.Text = 'Eccentricity [~]';
            app.RelativeErrorNAMenu_3 = uimenu(app.CEccentricity);
            app.RelativeErrorNAMenu_3.Text = 'Relative Error: N/A';
            app.CArgumentofPerigee = uicontextmenu(app.TLEMaster);
            % Assign app.CArgumentofPerigee
            app.TL2_5.ContextMenu = app.CArgumentofPerigee;
            app.CL2_5.ContextMenu = app.CArgumentofPerigee;
            app.ArguementofPerigeedegMenu = uimenu(app.CArgumentofPerigee);
            app.ArguementofPerigeedegMenu.Text = 'Arguement of Perigee [deg]';
            app.RelativeErrorNAMenu_4 = uimenu(app.CArgumentofPerigee);
            app.RelativeErrorNAMenu_4.Text = 'Relative Error: N/A';
            app.CMeanAnomaly = uicontextmenu(app.TLEMaster);
            % Assign app.CMeanAnomaly
            app.TL2_6.ContextMenu = app.CMeanAnomaly;
            app.CL2_6.ContextMenu = app.CMeanAnomaly;
            app.MeanAnomalydegMenu = uimenu(app.CMeanAnomaly);
            app.MeanAnomalydegMenu.Text = 'Mean Anomaly [deg]';
            app.RelativeErrorNAMenu_5 = uimenu(app.CMeanAnomaly);
            app.RelativeErrorNAMenu_5.Text = 'Relative Error: N/A';
            app.CMeanMotion = uicontextmenu(app.TLEMaster);
            % Assign app.CMeanMotion
            app.TL2_7.ContextMenu = app.CMeanMotion;
            app.CL2_7.ContextMenu = app.CMeanMotion;
            app.MeanMotionrevdayMenu = uimenu(app.CMeanMotion);
            app.MeanMotionrevdayMenu.Text = 'Mean Motion [rev/day]';
            app.RelativeErrorNAMenu_6 = uimenu(app.CMeanMotion);
            app.RelativeErrorNAMenu_6.Text = 'Relative Error: N/A';
            app.TCatalognum_Classification = uicontextmenu(app.TLEMaster);
            % Assign app.TCatalognum_Classification
            app.TL1_1.ContextMenu = app.TCatalognum_Classification;
            app.CatalogNumberClassificationMenu = uimenu(app.TCatalognum_Classification);
            app.CatalogNumberClassificationMenu.Text = 'Catalog Number/Classification';
            app.TSattelliteName = uicontextmenu(app.TLEMaster);
            % Assign app.TSattelliteName
            app.TRef_Name.ContextMenu = app.TSattelliteName;
            app.SatelliteNameMenu = uimenu(app.TSattelliteName);
            app.SatelliteNameMenu.Text = 'Satellite Name';
            app.TIntDesignator = uicontextmenu(app.TLEMaster);
            % Assign app.TIntDesignator
            app.TL1_2.ContextMenu = app.TIntDesignator;
            app.InternationalDesignatorMenu = uimenu(app.TIntDesignator);
            app.InternationalDesignatorMenu.Text = 'International Designator';
            app.TEpoch = uicontextmenu(app.TLEMaster);
            % Assign app.TEpoch
            app.TL1_3.ContextMenu = app.TEpoch;
            app.EpochdayandyearMenu = uimenu(app.TEpoch);
            app.EpochdayandyearMenu.Text = 'Epoch day and year';
            app.TFirstDerivative = uicontextmenu(app.TLEMaster);
            % Assign app.TFirstDerivative
            app.TL1_4.ContextMenu = app.TFirstDerivative;
            app.FirstDerivativeofMeanMotionMenu = uimenu(app.TFirstDerivative);
            app.FirstDerivativeofMeanMotionMenu.Text = 'First Derivative of Mean Motion';
            app.TSecondDerivative = uicontextmenu(app.TLEMaster);
            % Assign app.TSecondDerivative
            app.TL1_5.ContextMenu = app.TSecondDerivative;
            app.SecondDerivativeofMeanMotionMenu = uimenu(app.TSecondDerivative);
            app.SecondDerivativeofMeanMotionMenu.Text = 'Second Derivative of Mean Motion';
            app.Tdrag = uicontextmenu(app.TLEMaster);
            % Assign app.Tdrag
            app.TL1_6.ContextMenu = app.Tdrag;
            app.DragTermMenu = uimenu(app.Tdrag);
            app.DragTermMenu.Text = 'Drag Term';
            app.TEphemeris = uicontextmenu(app.TLEMaster);
            % Assign app.TEphemeris
            app.TL1_7.ContextMenu = app.TEphemeris;
            app.EphemeristypeMenu = uimenu(app.TEphemeris);
            app.EphemeristypeMenu.Text = 'Ephemeris type';
            app.TElement = uicontextmenu(app.TLEMaster);
            % Assign app.TElement
            app.TL1_8.ContextMenu = app.TElement;
            app.ElementSetNumberandChecksumMenu = uimenu(app.TElement);
            app.ElementSetNumberandChecksumMenu.Text = 'Element Set Number and Checksum';
            app.TSatelliteCatalog = uicontextmenu(app.TLEMaster);
            % Assign app.TSatelliteCatalog
            app.TL2_1.ContextMenu = app.TSatelliteCatalog;
            app.SatelliteCatalognumberMenu = uimenu(app.TSatelliteCatalog);
            app.SatelliteCatalognumberMenu.Text = 'Satellite Catalog number';
            app.ContextMenu = uicontextmenu(app.TLEMaster);
            % Assign app.ContextMenu
            app.AVGSpreadEditField.ContextMenu = app.ContextMenu;
            app.ForMaximumpossibleputin100Menu = uimenu(app.ContextMenu);
            app.ForMaximumpossibleputin100Menu.Text = 'For Maximum possible put in 100%';
            % Show the figure after all components are created
            app.TLEMaster.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TLE_DATA_V_1_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TLEMaster)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TLEMaster)
        end
    end
end